classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WelcomeLabel             matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        PleasemakesurethatLabel  matlab.ui.control.Label
        ConnecttorobotButton     matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        outputEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttorobotButton
        function ConnecttorobotButtonPushed(app, event)
            clc;
            clear all;
            rosshutdown;
            
            rosinit();
            if ispc == 1
                system('putty pi@10.42.0.187 -pw turtlebot -m /home/ros/start.txt &');
            elseif isunix ==1
                %system('remmina -c /home/ros/.remmina/1576261692914.remmina &');
                system('plink pi@10.42.0.187 -pw turtlebot -m /home/ros/start.txt &')
                %system('roscore')
            else
                disp('Platform not supported');
            end
            %node_list = nosnode('list');
            app.outputEditField.Value = "Connected";
            Bar = waitbar(0,'Connecting to remote computer ...');
            secs= 30;
            for i=1:secs
                waitbar(i/secs,Bar,'Connecting to remote computer ...');
                pause(1);
            end
            
            close(Bar)
            
            
            app2
            app.delete
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logounits.jpg';
            app.PleasemakesurethatLabel = uilabel(app.UIFigure);
            app.PleasemakesurethatLabel.Tooltip = {''};
            app.PleasemakesurethatLabel.Text = {'Please make sure that:'; ''};
            app.ConnecttorobotButton = uibutton(app.UIFigure, 'push');
            app.ConnecttorobotButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttorobotButtonPushed, true);
            app.ConnecttorobotButton.Text = 'Connect to robot';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'1) Rover is turned on;'; '2) Rover and remote computer share the same network;'; '3) Network is configured properly.'};
            app.outputEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end