classdef choose_network < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MasterCheckBox           matlab.ui.control.CheckBox
        IPAddressEditFieldLabel  matlab.ui.control.Label
        IPAddressEditField       matlab.ui.control.EditField
        NodeNameEditFieldLabel   matlab.ui.control.Label
        NodeNameEditField        matlab.ui.control.EditField
        OKButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        main; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.UIFigure.Name = 'ROS Master Selection';
            app.main = main_app;
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.MasterCheckBox.Value == true
                rosinit('localhost','NodeHost',app.IPAddressEditField.Value, "NodeName",app.NodeNameEditField.Value)
            else
                rosinit(app.IPAddressEditField.Value)
            end
            app.UIFigureCloseRequest()
        end

        % Value changing function: IPAddressEditField
        function IPAddressEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if isempty(regexp(changingValue,'^[0-9]+\.[0-9]+\.[0-9]+\.[0-9]+','match'))
                app.IPAddressEditField.BackgroundColor = 'Red';
            else
                app.IPAddressEditField.BackgroundColor = 'White';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MasterCheckBox = uicheckbox(app.UIFigure);
            app.MasterCheckBox.Text = 'Master';
            app.MasterCheckBox.Value = true;
            app.IPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressEditField.ValueChangingFcn = createCallbackFcn(app, @IPAddressEditFieldValueChanging, true);
            app.IPAddressEditField.Value = '0.0.0.0';
            app.NodeNameEditFieldLabel = uilabel(app.UIFigure);
            app.NodeNameEditFieldLabel.Text = 'Node Name';
            app.NodeNameEditField = uieditfield(app.UIFigure, 'text');
            app.NodeNameEditField.Value = '/test_node';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = choose_network(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end