classdef current_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        OptionsPanel                    matlab.ui.container.Panel
        EnableIndicatorsCheckBox        matlab.ui.control.CheckBox
        ShowGraphsCheckBox              matlab.ui.control.CheckBox
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        IndicatorsPanel                 matlab.ui.container.Panel
        FrontRightDriveMotorGaugeLabel  matlab.ui.control.Label
        FrontRightDriveMotorGauge       matlab.ui.control.Gauge
        BackRightDriveMotorGaugeLabel   matlab.ui.control.Label
        BackRightDriveMotorGauge        matlab.ui.control.Gauge
        FrontLeftDriveMotorGaugeLabel   matlab.ui.control.Label
        FrontLeftDriveMotorGauge        matlab.ui.control.Gauge
        BackLeftDriveMotorGaugeLabel    matlab.ui.control.Label
        BackLeftDriveMotorGauge         matlab.ui.control.Gauge
        FrontRightSteerMotorGaugeLabel  matlab.ui.control.Label
        FrontRightSteerMotorGauge       matlab.ui.control.Gauge
        BackRightSteerMotorGaugeLabel   matlab.ui.control.Label
        BackRightSteerMotorGauge        matlab.ui.control.Gauge
        FrontLeftSteerMotorGaugeLabel   matlab.ui.control.Label
        FrontLeftSteerMotorGauge        matlab.ui.control.Gauge
        BackLeftSteerMotorGaugeLabel    matlab.ui.control.Label
        BackLeftSteerMotorGauge         matlab.ui.control.Gauge
        RawDataPanel                    matlab.ui.container.Panel
        CurrentPanel                    matlab.ui.container.Panel
        T1                              matlab.ui.control.NumericEditField
        T2                              matlab.ui.control.NumericEditField
        T3                              matlab.ui.control.NumericEditField
        T4                              matlab.ui.control.NumericEditField
        T5                              matlab.ui.control.NumericEditField
        T6                              matlab.ui.control.NumericEditField
        T7                              matlab.ui.control.NumericEditField
        T8                              matlab.ui.control.NumericEditField
        BRDMLabel                       matlab.ui.control.Label
        FRDMLabel                       matlab.ui.control.Label
        BLDMLabel                       matlab.ui.control.Label
        FLDMLabel                       matlab.ui.control.Label
        BRSMLabel                       matlab.ui.control.Label
        FRSMLabel                       matlab.ui.control.Label
        BLSMLabel                       matlab.ui.control.Label
        FLSMLabel                       matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        initialized = false; % Description
        initial_time_plot;
        last_data;
        
        
        line_1;
        line_2;
        line_3;
        line_4;
        line_5;
        line_6;
        line_7;
        line_8;
        
        legend_current;
        plot_initialized = false;
        plot_resetted = true;
        
        
    end
    
    properties (Access = public)
        main_app; % Description
    end


    methods (Access = private)
        
        function initializePlot(app)
            hold(app.UIAxes,'on')
            app.line_1 = plot(app.UIAxes,0,0,'-r');
            app.line_2 = plot(app.UIAxes,0,0,'-b');
            app.line_3 = plot(app.UIAxes,0,0,'-g');
            app.line_4 = plot(app.UIAxes,0,0,'-k');
            app.line_5 = plot(app.UIAxes,0,0,'-.r');
            app.line_6 = plot(app.UIAxes,0,0,'-.b');
            app.line_7 = plot(app.UIAxes,0,0,'-.g');
            app.line_8 = plot(app.UIAxes,0,0,'-.k');
            app.legend_current = legend(app.UIAxes,...
                {'BRDM','FRDM','BLDM','FLDM','BRSM','FRSM','BLSM', 'FLSM'},"AutoUpdate","off");
            
            app.plot_initialized = true;
            
            
        end
        function resetPlot(app)
            app.line_1.XData = app.line_1.XData(1);
            app.line_1.YData = app.line_1.YData(1);
            
            app.line_2.XData = app.line_2.XData(1);
            app.line_2.YData = app.line_2.YData(1);
            
            app.line_3.XData = app.line_3.XData(1);
            app.line_3.YData = app.line_3.YData(1);
            
            app.line_4.XData = app.line_4.XData(1);
            app.line_4.YData = app.line_4.YData(1);
            
            app.line_5.XData = app.line_5.XData(1);
            app.line_5.YData = app.line_5.YData(1);
            
            app.line_6.XData = app.line_6.XData(1);
            app.line_6.YData = app.line_6.YData(1);
            
            app.line_7.XData = app.line_7.XData(1);
            app.line_7.YData = app.line_7.YData(1);
            
            app.line_8.XData = app.line_8.XData(1);
            app.line_8.YData = app.line_8.YData(1);
            
        end
        function updateIndicators(app, msg)
            scale_factor = app.ScaleFactorEditField.Value;
            
            app.BackRightDriveMotorGauge.Value = scale_factor*msg.BRWFDynamixelCurrent;
            app.FrontRightDriveMotorGauge.Value = scale_factor*msg.FRWFDynamixelCurrent;
            app.BackLeftDriveMotorGauge.Value = scale_factor*msg.BLWFDynamixelCurrent;
            app.FrontLeftDriveMotorGauge.Value = scale_factor*msg.FLWFDynamixelCurrent;
            
            app.BackRightSteerMotorGauge.Value = scale_factor*msg.BRWSDynamixelCurrent;
            app.FrontRightSteerMotorGauge.Value = scale_factor*msg.FRWSDynamixelCurrent;
            app.BackLeftSteerMotorGauge.Value = scale_factor*msg.BLWSDynamixelCurrent;
            app.FrontLeftSteerMotorGauge.Value = scale_factor*msg.FLWSDynamixelCurrent;


        end
        function updateRawData(app, msg)
            scale_factor = app.ScaleFactorEditField.Value;
            app.T1.Value = scale_factor*msg.BRWFDynamixelCurrent;
            app.T2.Value = scale_factor*msg.FRWFDynamixelCurrent;
            app.T3.Value = scale_factor*msg.BLWFDynamixelCurrent;
            app.T4.Value = scale_factor*msg.FLWFDynamixelCurrent;
            
            app.T5.Value = scale_factor*msg.BRWSDynamixelCurrent;
            app.T6.Value = scale_factor*msg.FRWSDynamixelCurrent;
            app.T7.Value = scale_factor*msg.BLWSDynamixelCurrent;
            app.T8.Value = scale_factor*msg.FLWSDynamixelCurrent;
            
        end
        function updateGraph(app,msg,time)
            scale_factor = app.ScaleFactorEditField.Value;
            if app.plot_resetted
                app.line_1.XData = time;
                app.line_1.YData = scale_factor*msg.BRWFDynamixelCurrent;
                
                app.line_2.XData = time;
                app.line_2.YData = scale_factor*msg.FRWFDynamixelCurrent;
                
                app.line_3.XData = time;
                app.line_3.YData = scale_factor*msg.BLWFDynamixelCurrent;
                
                app.line_4.XData = time;
                app.line_4.YData = scale_factor*msg.FLWFDynamixelCurrent;
                
                app.line_5.XData = time;
                app.line_5.YData = scale_factor*msg.BRWSDynamixelCurrent;
                
                app.line_6.XData = time;
                app.line_6.YData = scale_factor*msg.FRWSDynamixelCurrent;
                
                app.line_7.XData = time;
                app.line_7.YData = scale_factor*msg.BLWSDynamixelCurrent;
                
                app.line_8.XData = time;
                app.line_8.YData = scale_factor*msg.FLWSDynamixelCurrent;
                
                
            else
                app.line_1.XData(end+1) = time;
                app.line_1.YData(end+1) = scale_factor*msg.BRWFDynamixelCurrent;
                
                app.line_2.XData(end+1) = time;
                app.line_2.YData(end+1) = scale_factor*msg.FRWFDynamixelCurrent;
                
                app.line_3.XData(end+1) = time;
                app.line_3.YData(end+1) = scale_factor*msg.BLWFDynamixelCurrent;
                
                app.line_4.XData(end+1) = time;
                app.line_4.YData(end+1) = scale_factor*msg.FLWFDynamixelCurrent;
                
                app.line_5.XData(end+1) = time;
                app.line_5.YData(end+1) = scale_factor*msg.BRWSDynamixelCurrent;
                
                app.line_6.XData(end+1) = time;
                app.line_6.YData(end+1) = scale_factor*msg.FRWSDynamixelCurrent;
                
                app.line_7.XData(end+1) = time;
                app.line_7.YData(end+1) = scale_factor*msg.BLWSDynamixelCurrent;
                
                app.line_8.XData(end+1) = time;
                app.line_8.YData(end+1) = scale_factor*msg.FLWSDynamixelCurrent;
                
            end
            
        end


    end
    methods (Access=private,Static)
        function new_data = convertdataToDouble(data)
            new_data = struct();
            new_data.Header = data.Header;
            
            new_data.BRWFDynamixelCurrent = double(data.BRWFDynamixelCurrent)+eps;
            new_data.FRWFDynamixelCurrent = double(data.FRWFDynamixelCurrent)+eps;
            new_data.BLWFDynamixelCurrent = double(data.BLWFDynamixelCurrent)+eps;
            new_data.FLWFDynamixelCurrent = double(data.FLWFDynamixelCurrent)+eps;
            new_data.BRWSDynamixelCurrent = double(data.BRWSDynamixelCurrent)+eps;
            new_data.FRWSDynamixelCurrent = double(data.FRWSDynamixelCurrent)+eps;
            new_data.BLWSDynamixelCurrent = double(data.BLWSDynamixelCurrent)+eps;
            new_data.FLWSDynamixelCurrent = double(data.FLWSDynamixelCurrent)+eps;
            
        end
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            data = app.main_app.collected_data.motor_current{end};
            app.last_data = data;
            data = app.convertdataToDouble(data);
            
            app.updateRawData(data);
            
            if app.EnableIndicatorsCheckBox.Value == true
                app.updateIndicators(data)
            end
            
            if app.ShowGraphsCheckBox.Value == true
                time = data.Header.Stamp - app.initial_time_plot;
                time1 = time.Sec + time.Nsec/1e9;
                
                app.updateGraph(data,time1)
                
                if app.plot_resetted
                    app.plot_resetted = false;
                end
            end
            
            
        end
        function flag = isInitialized(app)
            flag = app.initialized;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.UIFigure.Name = 'Motor Current Data View';
            app.main_app = calling_app;
            app.initializePlot()
            app.initialized = true;
            
        end

        % Value changed function: ShowGraphsCheckBox
        function ShowGraphsCheckBoxValueChanged(app, event)
            value = app.ShowGraphsCheckBox.Value;
            
            app.plot_resetted = true;
            
            if value == true
                app.resetPlot()
                app.initial_time_plot = app.last_data.Header.Stamp;
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            app.main_app.MotorCurrentButton.Enable = true;
            app.main_app.motor_curr_window = 0;
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.OptionsPanel = uipanel(app.SummaryTab);
            app.OptionsPanel.Title = 'Options';
            app.EnableIndicatorsCheckBox = uicheckbox(app.OptionsPanel);
            app.EnableIndicatorsCheckBox.Text = 'Enable Indicators';
            app.ShowGraphsCheckBox = uicheckbox(app.OptionsPanel);
            app.ShowGraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphsCheckBoxValueChanged, true);
            app.ShowGraphsCheckBox.Text = 'Show Graphs';
            app.ScaleFactorEditFieldLabel = uilabel(app.OptionsPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ScaleFactorEditField.Value = 2.69;
            app.IndicatorsPanel = uipanel(app.SummaryTab);
            app.IndicatorsPanel.Title = 'Indicators';
            app.FrontRightDriveMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.FrontRightDriveMotorGaugeLabel.Text = 'Front-Right-Drive-Motor';
            app.FrontRightDriveMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.FrontRightDriveMotorGauge.Limits = [0 3209.17];
            app.FrontRightDriveMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.BackRightDriveMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.BackRightDriveMotorGaugeLabel.Text = 'Back-Right-Drive-Motor';
            app.BackRightDriveMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.BackRightDriveMotorGauge.Limits = [0 3209.17];
            app.BackRightDriveMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.FrontLeftDriveMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.FrontLeftDriveMotorGaugeLabel.Text = 'Front-Left-Drive-Motor';
            app.FrontLeftDriveMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.FrontLeftDriveMotorGauge.Limits = [0 3209.17];
            app.FrontLeftDriveMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.BackLeftDriveMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.BackLeftDriveMotorGaugeLabel.Text = 'Back-Left-Drive-Motor';
            app.BackLeftDriveMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.BackLeftDriveMotorGauge.Limits = [0 3209.17];
            app.BackLeftDriveMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.FrontRightSteerMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.FrontRightSteerMotorGaugeLabel.Text = 'Front-Right-Steer-Motor';
            app.FrontRightSteerMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.FrontRightSteerMotorGauge.Limits = [0 3209.17];
            app.FrontRightSteerMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.BackRightSteerMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.BackRightSteerMotorGaugeLabel.Text = 'Back-Right-Steer-Motor';
            app.BackRightSteerMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.BackRightSteerMotorGauge.Limits = [0 3209.17];
            app.BackRightSteerMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.FrontLeftSteerMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.FrontLeftSteerMotorGaugeLabel.Text = 'Front-Left-Steer-Motor';
            app.FrontLeftSteerMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.FrontLeftSteerMotorGauge.Limits = [0 3209.17];
            app.FrontLeftSteerMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.BackLeftSteerMotorGaugeLabel = uilabel(app.IndicatorsPanel);
            app.BackLeftSteerMotorGaugeLabel.Text = 'Back-Left-Steer-Motor';
            app.BackLeftSteerMotorGauge = uigauge(app.IndicatorsPanel, 'circular');
            app.BackLeftSteerMotorGauge.Limits = [0 3209.17];
            app.BackLeftSteerMotorGauge.MajorTicks = [0 500 1000 1500 2000 2500 3000];
            app.RawDataPanel = uipanel(app.SummaryTab);
            app.RawDataPanel.Title = 'Raw Data';
            app.CurrentPanel = uipanel(app.RawDataPanel);
            app.CurrentPanel.Title = 'Current';
            app.T1 = uieditfield(app.CurrentPanel, 'numeric');
            app.T1.Editable = 'off';
            app.T2 = uieditfield(app.CurrentPanel, 'numeric');
            app.T2.Editable = 'off';
            app.T3 = uieditfield(app.CurrentPanel, 'numeric');
            app.T3.Editable = 'off';
            app.T4 = uieditfield(app.CurrentPanel, 'numeric');
            app.T4.Editable = 'off';
            app.T5 = uieditfield(app.CurrentPanel, 'numeric');
            app.T5.Editable = 'off';
            app.T6 = uieditfield(app.CurrentPanel, 'numeric');
            app.T6.Editable = 'off';
            app.T7 = uieditfield(app.CurrentPanel, 'numeric');
            app.T7.Editable = 'off';
            app.T8 = uieditfield(app.CurrentPanel, 'numeric');
            app.T8.Editable = 'off';
            app.BRDMLabel = uilabel(app.RawDataPanel);
            app.BRDMLabel.Text = 'BRDM';
            app.FRDMLabel = uilabel(app.RawDataPanel);
            app.FRDMLabel.Text = 'FRDM';
            app.BLDMLabel = uilabel(app.RawDataPanel);
            app.BLDMLabel.Text = 'BLDM';
            app.FLDMLabel = uilabel(app.RawDataPanel);
            app.FLDMLabel.Text = 'FLDM';
            app.BRSMLabel = uilabel(app.RawDataPanel);
            app.BRSMLabel.Text = 'BRSM';
            app.FRSMLabel = uilabel(app.RawDataPanel);
            app.FRSMLabel.Text = 'FRSM';
            app.BLSMLabel = uilabel(app.RawDataPanel);
            app.BLSMLabel.Text = 'BLSM';
            app.FLSMLabel = uilabel(app.RawDataPanel);
            app.FLSMLabel.Text = 'FLSM';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, 'Motors Current')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'Current [mA]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = current_visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end