classdef data_aquisition_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OdometryCheckBox          matlab.ui.control.CheckBox
        ImuCheckBox               matlab.ui.control.CheckBox
        JointStatesCheckBox       matlab.ui.control.CheckBox
        MotorTemperatureCheckBox  matlab.ui.control.CheckBox
        MotorCurrentCheckBox      matlab.ui.control.CheckBox
        MagneticFieldCheckBox     matlab.ui.control.CheckBox
        SensorStatesCheckBox      matlab.ui.control.CheckBox
        OKButton                  matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        AllCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        main_app; % Description
        topics;
    end
    
    methods (Access = private)
        
        function reset(app)
            app.topics = struct();
            app.topics.odometry.selected = false;
            app.topics.imu.selected = false;
            app.topics.joint_states.selected = false;
            app.topics.motor_temperature.selected = false;
            app.topics.motor_current.selected = false;
            app.topics.magnetic_field.selected = false;
            app.topics.sensor_states.selected = false;
        end
        
        function updateValues(app)
            app.topics.odometry.selected = app.OdometryCheckBox.Value;
            app.topics.imu.selected = app.ImuCheckBox.Value;
            app.topics.joint_states.selected = app.JointStatesCheckBox.Value;
            app.topics.motor_temperature.selected = app.MotorTemperatureCheckBox.Value;
            app.topics.motor_current.selected = app.MotorCurrentCheckBox.Value;
            app.topics.magnetic_field.selected = app.MagneticFieldCheckBox.Value;
            app.topics.sensor_states.selected = app.SensorStatesCheckBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            app.UIFigure.Name = 'Data Aquisition Selection';
            app.UIFigure.Resize = 'off';
            app.reset()
            
            
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
                value = app.AllCheckBox.Value;    
                app.OdometryCheckBox.Value = value;
                app.ImuCheckBox.Value = value;
                app.JointStatesCheckBox.Value = value;
                app.MotorTemperatureCheckBox.Value = value;
                app.MotorCurrentCheckBox.Value = value;
                app.MagneticFieldCheckBox.Value = value;
                app.SensorStatesCheckBox.Value = value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.updateValues()
            app.main_app.selected_topics_aquisition = app.topics;
            assignin('base','diocane',app.main_app.selected_topics_aquisition)
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.reset()
            app.main_app.selected_topics_aquisition = app.topics;
            app.UIFigureCloseRequest()
        end

        % Value changed function: OdometryCheckBox
        function OdometryCheckBoxValueChanged(app, event)
            value = app.OdometryCheckBox.Value;
            app.topics.odometry.selected = value;
            disp('DIOSCHIFOSO')
        end

        % Value changed function: ImuCheckBox
        function ImuCheckBoxValueChanged(app, event)
            value = app.ImuCheckBox.Value;
            app.topics.imu.selected = value;
        end

        % Value changed function: JointStatesCheckBox
        function JointStatesCheckBoxValueChanged(app, event)
            value = app.JointStatesCheckBox.Value;
            app.topics.joint_states.selected = value;
        end

        % Value changed function: MotorTemperatureCheckBox
        function MotorTemperatureCheckBoxValueChanged(app, event)
            value = app.MotorTemperatureCheckBox.Value;
            app.topics.motor_temperature.selected = value;
        end

        % Value changed function: MotorCurrentCheckBox
        function MotorCurrentCheckBoxValueChanged(app, event)
            value = app.MotorCurrentCheckBox.Value;
            app.topics.motor_current.selected = value;
        end

        % Value changed function: MagneticFieldCheckBox
        function MagneticFieldCheckBoxValueChanged(app, event)
            value = app.MagneticFieldCheckBox.Value;
            app.topics.magnetic_field.selected = value;
        end

        % Value changed function: SensorStatesCheckBox
        function SensorStatesCheckBoxValueChanged(app, event)
            value = app.SensorStatesCheckBox.Value;
            app.topics.sensor_states.selected = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OdometryCheckBox = uicheckbox(app.UIFigure);
            app.OdometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @OdometryCheckBoxValueChanged, true);
            app.OdometryCheckBox.Text = 'Odometry';
            app.ImuCheckBox = uicheckbox(app.UIFigure);
            app.ImuCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImuCheckBoxValueChanged, true);
            app.ImuCheckBox.Text = 'Imu';
            app.JointStatesCheckBox = uicheckbox(app.UIFigure);
            app.JointStatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointStatesCheckBoxValueChanged, true);
            app.JointStatesCheckBox.Text = 'Joint States';
            app.MotorTemperatureCheckBox = uicheckbox(app.UIFigure);
            app.MotorTemperatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorTemperatureCheckBoxValueChanged, true);
            app.MotorTemperatureCheckBox.Text = 'Motor Temperature';
            app.MotorCurrentCheckBox = uicheckbox(app.UIFigure);
            app.MotorCurrentCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorCurrentCheckBoxValueChanged, true);
            app.MotorCurrentCheckBox.Text = 'Motor Current';
            app.MagneticFieldCheckBox = uicheckbox(app.UIFigure);
            app.MagneticFieldCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagneticFieldCheckBoxValueChanged, true);
            app.MagneticFieldCheckBox.Text = 'Magnetic Field';
            app.SensorStatesCheckBox = uicheckbox(app.UIFigure);
            app.SensorStatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorStatesCheckBoxValueChanged, true);
            app.SensorStatesCheckBox.Text = 'Sensor States';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AllCheckBox = uicheckbox(app.UIFigure);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_aquisition_selection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end