classdef imu_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SummaryTab                matlab.ui.container.Tab
        RawDataPanel              matlab.ui.container.Panel
        LinearAccelerationPanel   matlab.ui.container.Panel
        ZEditField_2              matlab.ui.control.NumericEditField
        ZEditField_2Label         matlab.ui.control.Label
        YEditField_5              matlab.ui.control.NumericEditField
        YEditField_5Label         matlab.ui.control.Label
        XEditField_2              matlab.ui.control.NumericEditField
        XEditField_2Label         matlab.ui.control.Label
        AngularVelocityPanel      matlab.ui.container.Panel
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        YEditField_3              matlab.ui.control.NumericEditField
        YEditField_3Label         matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        OrientationPanel          matlab.ui.container.Panel
        YEditField_6              matlab.ui.control.NumericEditField
        YEditField_6Label         matlab.ui.control.Label
        PEditField_2              matlab.ui.control.NumericEditField
        PEditField_2Label         matlab.ui.control.Label
        REditField_2              matlab.ui.control.NumericEditField
        REditField_2Label         matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        PEditField                matlab.ui.control.NumericEditField
        PEditFieldLabel           matlab.ui.control.Label
        REditField                matlab.ui.control.NumericEditField
        REditFieldLabel           matlab.ui.control.Label
        OptionsPanel              matlab.ui.container.Panel
        ShowGraphsCheckBox        matlab.ui.control.CheckBox
        EnableIndicatorsCheckBox  matlab.ui.control.CheckBox
        IndicatorsPanel           matlab.ui.container.Panel
        HeadingIndicator          Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel     matlab.ui.control.Label
        ArtificialHorizon         Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel    matlab.ui.control.Label
        GraphsTab                 matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        OrientationTab            matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        AngularVelocityTab        matlab.ui.container.Tab
        UIAxes_2                  matlab.ui.control.UIAxes
        AccelerationTab           matlab.ui.container.Tab
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        main_app; % Description
    end
    
    properties (Access = private)
        line_orient_x;
        line_orient_y;
        line_orient_z;
        
        line_ang_vel_x;
        line_ang_vel_y;
        line_ang_vel_z;
        
        line_acc_x;
        line_acc_y;
        line_acc_z;
        
        plots_initialized = false;
        plots_resetted = true;
       
       
        % Description
        timer_data;
        initial_time_plot;
        
        is_fake = false;
        orientation_legend;
        ang_vel_legend;
        acc_legend;
        
        initialized = false;
        last_data;
        
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            %Uncomment for real hardware
            data = app.main_app.collected_data.imu{end};
            
            %Comment for real hardware
            %data = app.fakeDataGeneration();
            app.last_data = data;
            
            %Update Raw Data
            quat = [data.Orientation.W, data.Orientation.X, data.Orientation.Y, data.Orientation.Z];
            [yaw,pitch, roll] = quat2angle(quat);
            
            pitch = -pitch;

            app.REditField.Value = roll;
            app.PEditField.Value = pitch;
            app.YEditField.Value = yaw;
            
            app.REditField_2.Value = rad2deg(roll);
            app.PEditField_2.Value = rad2deg(pitch);
            app.YEditField_6.Value = rad2deg(yaw);
            
            app.XEditField.Value = data.AngularVelocity.X;
            app.YEditField_3.Value = data.AngularVelocity.Y;
            app.ZEditField.Value = data.AngularVelocity.Z;
            
            app.XEditField_2.Value = data.LinearAcceleration.X;
            app.YEditField_5.Value = data.LinearAcceleration.Y;
            app.ZEditField_2.Value = data.LinearAcceleration.Z;
            
            
            if app.EnableIndicatorsCheckBox.Value == true
                app.ArtificialHorizon.Roll = rad2deg(roll);
                app.ArtificialHorizon.Pitch = rad2deg(pitch);
                app.HeadingIndicator.Heading = rad2deg(yaw);
            end
            
            if app.ShowGraphsCheckBox.Value == true
                time = data.Header.Stamp - app.initial_time_plot;
                time1 = time.Sec + time.Nsec/1e9;
                tic;
                app.updateOrientationGraph(roll,pitch,yaw, time1);
                app.updateAngularVelocityGraph(data.AngularVelocity.X, data.AngularVelocity.Y, data.AngularVelocity.Z, time1);
                app.updateLinearAcceleration(data.LinearAcceleration.X, data.LinearAcceleration.Y, data.LinearAcceleration.Z,time1);
                toc;
                if app.plots_resetted
                    app.plots_resetted = false;
                end

            end
            
        end
        
        function flag = isInitialized(app)
            flag = app.initialized;

        end
    end
    
    methods (Access = private, Static)
        
        function data = fakeDataGeneration()
            data = struct();
            data.Header.Stamp = rostime('now');
            q = randrot().compact();
            
            data.Orientation.X = q(2);
            data.Orientation.Y = q(3);
            data.Orientation.Z = q(4);
            data.Orientation.W = q(1);
            
            data.AngularVelocity.X = rand();
            data.AngularVelocity.Y = rand();
            data.AngularVelocity.Z = rand();
            
            data.LinearAcceleration.X = rand();
            data.LinearAcceleration.Y = rand();
            data.LinearAcceleration.Z = rand();
            
            
        end
    end
        
    methods (Access = private)
        function initializeFakeData(app)
            app.is_fake = true;
            
            app.timer_data = timer();
            app.timer_data.Period = 0.1;
            app.timer_data.BusyMode = 'drop';
            app.timer_data.ExecutionMode = 'fixedRate';
            app.timer_data.TimerFcn = @(~,~)app.updatePlots;
            app.timer_data.start()
        end


        function updateOrientationGraph(app, roll, pitch, yaw, time1)
                if app.plots_resetted
                    app.line_orient_x.XData = time1;
                    app.line_orient_x.YData = roll;
                else
                    app.line_orient_x.XData(end+1) = time1;
                    app.line_orient_x.YData(end+1) = roll;
                end
                
                if app.plots_resetted
                    app.line_orient_y.XData = time1;
                    app.line_orient_y.YData = pitch;
                else
                    app.line_orient_y.XData(end+1) = time1;
                    app.line_orient_y.YData(end+1) = pitch;
                end
                
                if app.plots_resetted
                    app.line_orient_z.XData = time1;
                    app.line_orient_z.YData = yaw;
                else
                    app.line_orient_z.XData(end+1) = time1;
                    app.line_orient_z.YData(end+1) = yaw;
                end
        end
        
        function updateAngularVelocityGraph(app, ang_vel_x, ang_vel_y,ang_vel_z, time1)
                if app.plots_resetted
                    app.line_ang_vel_x.XData = time1;
                    app.line_ang_vel_x.YData = ang_vel_x;
                else
                    app.line_ang_vel_x.XData(end+1) = time1;
                    app.line_ang_vel_x.YData(end+1) = ang_vel_x;
                end
                
                if app.plots_resetted
                    app.line_ang_vel_y.XData = time1;
                    app.line_ang_vel_y.YData = ang_vel_y;
                else
                    app.line_ang_vel_y.XData(end+1) = time1;
                    app.line_ang_vel_y.YData(end+1) = ang_vel_y;
                end
                
                if app.plots_resetted
                    app.line_ang_vel_z.XData = time1;
                    app.line_ang_vel_z.YData = ang_vel_z;
                else
                    app.line_ang_vel_z.XData(end+1) = time1;
                    app.line_ang_vel_z.YData(end+1) = ang_vel_z;
                end
        end
        
        function updateLinearAcceleration(app, acc_x, acc_y, acc_z, time1)
                if app.plots_resetted
                    app.line_acc_x.XData = time1;
                    app.line_acc_x.YData = acc_x;
                else
                    app.line_acc_x.XData(end+1) = time1;
                    app.line_acc_x.YData(end+1) = acc_x;
                end
                
                if app.plots_resetted
                    app.line_acc_y.XData = time1;
                    app.line_acc_y.YData = acc_y;
                else
                    app.line_acc_y.XData(end+1) = time1;
                    app.line_acc_y.YData(end+1) = acc_y;
                end
                
                if app.plots_resetted
                    app.line_acc_z.XData = time1;
                    app.line_acc_z.YData = acc_z;
                else
                    app.line_acc_z.XData(end+1) = time1;
                    app.line_acc_z.YData(end+1) = acc_z;
                end
        end
        
        function initializePlots(app)
            %Initialize Orientation plot
            hold(app.UIAxes,'on')
            app.line_orient_x = plot(app.UIAxes,0,0,'-r');
            app.line_orient_y = plot(app.UIAxes,0,0,'-b');
            app.line_orient_z = plot(app.UIAxes,0,0,'-g');
            app.orientation_legend = legend(app.UIAxes,{'Roll','Pitch','Yaw'},"AutoUpdate","off");
            %app.orientation_legend.String = {'Roll','Pitch','Yaw'};
            
            
            %Initialize Velocity plot
            hold(app.UIAxes_2,'on')
            app.line_ang_vel_x = plot(app.UIAxes_2,0,0,'-r');
            app.line_ang_vel_y = plot(app.UIAxes_2,0,0,'-b');
            app.line_ang_vel_z = plot(app.UIAxes_2,0,0,'-g');
            app.ang_vel_legend = legend(app.UIAxes_2,{'X','Y','Z'},"AutoUpdate","off");
            
            
            %Initialize Acceleration plot
            hold(app.UIAxes_3,'on')
            app.line_acc_x = plot(app.UIAxes_3,0,0,'-r');
            app.line_acc_y = plot(app.UIAxes_3,0,0,'-b');
            app.line_acc_z = plot(app.UIAxes_3,0,0,'-g');
            app.acc_legend = legend(app.UIAxes_3,{'X','Y','Z'},"AutoUpdate","off");
            
            
            app.plots_initialized = true;
            
        end
        function resetPlots(app)
            app.line_orient_x.XData = app.line_orient_x.XData(1);
            app.line_orient_x.YData = app.line_orient_x.YData(1);
            
            app.line_orient_y.XData = app.line_orient_y.XData(1);
            app.line_orient_y.YData = app.line_orient_y.YData(1);
            
            app.line_orient_z.XData = app.line_orient_z.XData(1);
            app.line_orient_z.YData = app.line_orient_z.YData(1);
            
            app.line_ang_vel_x.XData = app.line_ang_vel_x.XData(1);
            app.line_ang_vel_x.YData = app.line_ang_vel_x.YData(1);
            
            app.line_ang_vel_y.XData = app.line_ang_vel_y.XData(1);
            app.line_ang_vel_y.YData = app.line_ang_vel_y.YData(1);
            
            app.line_ang_vel_z.XData = app.line_ang_vel_z.XData(1);
            app.line_ang_vel_z.YData = app.line_ang_vel_z.YData(1);
            
            app.line_acc_x.XData = app.line_acc_x.XData(1);
            app.line_acc_x.YData = app.line_acc_x.YData(1);
            
            app.line_acc_y.XData = app.line_acc_y.XData(1);
            app.line_acc_y.YData = app.line_acc_y.YData(1);
            
            app.line_acc_y.XData = app.line_acc_y.XData(1);
            app.line_acc_y.YData = app.line_acc_y.YData(1);
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.UIFigure.Name = 'IMU Data View';
            app.main_app = calling_app;
            app.initializePlots() %Plot initialization
            app.TabGroup2.Parent = app.GraphsTab;
            %app.initializeFakeData()
            
            
            app.initialized = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.is_fake == true
                app.timer_data.stop()
                app.timer_data.delete()
            end
            
            app.main_app.imu_window = 0;
            app.main_app.ImuButton.Enable = true;
            
            
            delete(app)
            
        end

        % Size changed function: GraphsTab
        function GraphsTabSizeChanged(app, event)
            position = app.GraphsTab.Position;
            app.TabGroup2.Position = [0,0,position(3:4)];
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %position = app.UIFigure.Position;
            
        end

        % Value changed function: ShowGraphsCheckBox
        function ShowGraphsCheckBoxValueChanged(app, event)
            value = app.ShowGraphsCheckBox.Value;
            app.plots_resetted = true;
            
            
            if value == true
                app.resetPlots()
                app.initial_time_plot = app.last_data.Header.Stamp;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.IndicatorsPanel = uipanel(app.SummaryTab);
            app.IndicatorsPanel.Title = 'Indicators';
            app.ArtificialHorizonLabel = uilabel(app.IndicatorsPanel);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.IndicatorsPanel);
            app.HeadingIndicatorLabel = uilabel(app.IndicatorsPanel);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.IndicatorsPanel);
            app.OptionsPanel = uipanel(app.SummaryTab);
            app.OptionsPanel.Title = 'Options';
            app.EnableIndicatorsCheckBox = uicheckbox(app.OptionsPanel);
            app.EnableIndicatorsCheckBox.Text = 'Enable Indicators';
            app.ShowGraphsCheckBox = uicheckbox(app.OptionsPanel);
            app.ShowGraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphsCheckBoxValueChanged, true);
            app.ShowGraphsCheckBox.Text = 'Show Graphs';
            app.RawDataPanel = uipanel(app.SummaryTab);
            app.RawDataPanel.Title = 'Raw Data';
            app.OrientationPanel = uipanel(app.RawDataPanel);
            app.OrientationPanel.Title = 'Orientation';
            app.REditFieldLabel = uilabel(app.OrientationPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.OrientationPanel, 'numeric');
            app.PEditFieldLabel = uilabel(app.OrientationPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.OrientationPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.OrientationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.OrientationPanel, 'numeric');
            app.REditField_2Label = uilabel(app.OrientationPanel);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.OrientationPanel, 'numeric');
            app.PEditField_2Label = uilabel(app.OrientationPanel);
            app.PEditField_2Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.OrientationPanel, 'numeric');
            app.YEditField_6Label = uilabel(app.OrientationPanel);
            app.YEditField_6Label.Text = 'Y';
            app.YEditField_6 = uieditfield(app.OrientationPanel, 'numeric');
            app.AngularVelocityPanel = uipanel(app.RawDataPanel);
            app.AngularVelocityPanel.Title = 'Angular Velocity';
            app.XEditFieldLabel = uilabel(app.AngularVelocityPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.AngularVelocityPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.AngularVelocityPanel);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.AngularVelocityPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.AngularVelocityPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.AngularVelocityPanel, 'numeric');
            app.LinearAccelerationPanel = uipanel(app.RawDataPanel);
            app.LinearAccelerationPanel.Title = 'Linear Acceleration';
            app.XEditField_2Label = uilabel(app.LinearAccelerationPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.LinearAccelerationPanel, 'numeric');
            app.YEditField_5Label = uilabel(app.LinearAccelerationPanel);
            app.YEditField_5Label.Text = 'Y';
            app.YEditField_5 = uieditfield(app.LinearAccelerationPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.LinearAccelerationPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.LinearAccelerationPanel, 'numeric');
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.SizeChangedFcn = createCallbackFcn(app, @GraphsTabSizeChanged, true);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.OrientationTab = uitab(app.TabGroup2);
            app.OrientationTab.Title = 'Orientation';
            app.UIAxes = uiaxes(app.OrientationTab);
            xlabel(app.UIAxes, 'time[t]')
            ylabel(app.UIAxes, 'Orientation [rad]')
            app.AngularVelocityTab = uitab(app.TabGroup2);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.UIAxes_2 = uiaxes(app.AngularVelocityTab);
            xlabel(app.UIAxes_2, 'time[t]')
            ylabel(app.UIAxes_2, 'Angular Velocity [rad/s]')
            app.AccelerationTab = uitab(app.TabGroup2);
            app.AccelerationTab.Title = 'Acceleration';
            app.UIAxes_3 = uiaxes(app.AccelerationTab);
            xlabel(app.UIAxes_3, 'time[t]')
            ylabel(app.UIAxes_3, 'Linear Acceleration [m/s^2]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imu_visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end