classdef joint_states_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        RPMIndicatorsPanel              matlab.ui.container.Panel
        BackRightDriveMotorRPMIndicatorLabel  matlab.ui.control.Label
        BackRightDriveMotorRPMIndicator  Aero.ui.control.RPMIndicator
        FrontRightDriveMotorRPMIndicatorLabel  matlab.ui.control.Label
        FrontRightDriveMotorRPMIndicator  Aero.ui.control.RPMIndicator
        BackLeftDriveMotorRPMIndicatorLabel  matlab.ui.control.Label
        BackLeftDriveMotorRPMIndicator  Aero.ui.control.RPMIndicator
        FrontLeftDriveMotorRPMIndicatorLabel  matlab.ui.control.Label
        FrontLeftDriveMotorRPMIndicator  Aero.ui.control.RPMIndicator
        BackRightSteerMotorRPMIndicatorLabel  matlab.ui.control.Label
        BackRightSteerMotorRPMIndicator  Aero.ui.control.RPMIndicator
        FrontRightSteerMotorRPMIndicatorLabel  matlab.ui.control.Label
        FrontRightSteerMotorRPMIndicator  Aero.ui.control.RPMIndicator
        BackLeftSteerMotorRPMIndicatorLabel  matlab.ui.control.Label
        BackLeftSteerMotorRPMIndicator  Aero.ui.control.RPMIndicator
        FrontLeftSteerMotorRPMIndicatorLabel  matlab.ui.control.Label
        FrontLeftSteerMotorRPMIndicator  Aero.ui.control.RPMIndicator
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        RawDataPanel                    matlab.ui.container.Panel
        EncoderPanel                    matlab.ui.container.Panel
        Enc1                            matlab.ui.control.NumericEditField
        Enc2                            matlab.ui.control.NumericEditField
        Enc3                            matlab.ui.control.NumericEditField
        Enc4                            matlab.ui.control.NumericEditField
        Enc5                            matlab.ui.control.NumericEditField
        Enc6                            matlab.ui.control.NumericEditField
        Enc7                            matlab.ui.control.NumericEditField
        Enc8                            matlab.ui.control.NumericEditField
        BRDMLabel                       matlab.ui.control.Label
        FRDMLabel                       matlab.ui.control.Label
        BLDMLabel                       matlab.ui.control.Label
        FLDMLabel                       matlab.ui.control.Label
        BRSMLabel                       matlab.ui.control.Label
        FRSMLabel                       matlab.ui.control.Label
        BLSMLabel                       matlab.ui.control.Label
        FLSMLabel                       matlab.ui.control.Label
        PositionPanel                   matlab.ui.container.Panel
        Pos1                            matlab.ui.control.NumericEditField
        Pos2                            matlab.ui.control.NumericEditField
        Pos3                            matlab.ui.control.NumericEditField
        Pos4                            matlab.ui.control.NumericEditField
        Pos5                            matlab.ui.control.NumericEditField
        Pos6                            matlab.ui.control.NumericEditField
        Pos7                            matlab.ui.control.NumericEditField
        Pos8                            matlab.ui.control.NumericEditField
        VelocityPanel                   matlab.ui.container.Panel
        Vel1                            matlab.ui.control.NumericEditField
        Vel2                            matlab.ui.control.NumericEditField
        Vel3                            matlab.ui.control.NumericEditField
        Vel4                            matlab.ui.control.NumericEditField
        Vel5                            matlab.ui.control.NumericEditField
        Vel6                            matlab.ui.control.NumericEditField
        Vel7                            matlab.ui.control.NumericEditField
        Vel8                            matlab.ui.control.NumericEditField
        OptionsPanel                    matlab.ui.container.Panel
        EnableIndicatorsCheckBox        matlab.ui.control.CheckBox
        ShowGraphsCheckBox              matlab.ui.control.CheckBox
        DatatoShowButtonGroup           matlab.ui.container.ButtonGroup
        EncoderButton                   matlab.ui.control.RadioButton
        PositionButton                  matlab.ui.control.RadioButton
        VelocityButton                  matlab.ui.control.RadioButton
        GraphTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        EncodersTab                     matlab.ui.container.Tab
        DriveWheelsPanel                matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SteerWheelsPanel                matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        PositionTab                     matlab.ui.container.Tab
        DriveWheelsPanel_2              matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        SteerWheelsPanel_2              matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        VelocityTab                     matlab.ui.container.Tab
        DriveWheelsPanel_3              matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        SteerWheelsPanel_3              matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        main_app % Description
        rads2rpm = 60/(2*pi);
        lamp_update = 1;
    end
    
    properties (Access = private)
        initialized = false; % Description
        plots_resetted;
        plots_initialized;
        
        last_message;
        
        
        initial_plot_time;
        
        lines_encoders_drive;
        lines_encoders_steer;
        
        lines_position_drive;
        lines_position_steer;
        
        lines_velocity_drive;
        lines_velocity_steer;
        
        encoders_legend_drive;
        encoders_legend_steer;
        
        position_legend_drive;
        position_legend_steer;
        
        
        velocity_legend_drive;
        velocity_legend_steer;


    end
    
    methods (Access = public)
        
        function updatePlots(app)
            data = app.main_app.collected_data.joint_states{end};
            app.last_message = data;
            
            
            %Update RPMS
            if app.EnableIndicatorsCheckBox.Value == true
                app.lamp_update = app.lamp_update +1;
                
                app.updateIndicators(data)
                
            
            %Update Lamps
                if mod(app.lamp_update, 100) == 0
                    app.updateLamps(data)
                end
                
            
            end
            
            app.updateRawData(data)
            
            
            if app.ShowGraphsCheckBox.Value == true
                %Update Plots
                time = data.Header.Stamp - app.initial_plot_time;
                time1 = time.Sec + time.Nsec/1e9;
                
                app.updateGraphs(data,time1);
                if app.plots_resetted
                    app.plots_resetted = false;
                end
            end
        end
        
        function flag = isInitialized(app)
            flag = app.initialized;
        end
    end
    
    methods (Access = private)
        function initializeGraphs(app)
            colors = {'-r','-b','-g','-k'};
            names_drive = {'BRWF','FRWF','BLWF','FLWF'};
            names_steer = {'BRWS','FRWS','BLWS','FLWS'};
            app.lines_encoders_drive = cell(1,4);
            app.lines_encoders_steer = cell(1,4);
            
            app.lines_position_drive = cell(1,4);
            app.lines_position_steer = cell(1,4);
            
            app.lines_velocity_drive = cell(1,4);
            app.lines_velocity_steer = cell(1,4);
            
            %Hold the axes
            hold(app.UIAxes,'on');
            hold(app.UIAxes_2,'on');
            hold(app.UIAxes_3,'on');
            hold(app.UIAxes_4, 'on');
            hold(app.UIAxes_5,'on');
            hold(app.UIAxes_6,'on');
            
            for i=1:numel(app.lines_encoders_drive)
                app.lines_encoders_drive{i} = plot(app.UIAxes,0,0,colors{i});
                app.lines_encoders_steer{i} = plot(app.UIAxes_2,0,0, colors{i});
                
                app.lines_position_drive{i} = plot(app.UIAxes_3,0,0,colors{i});
                app.lines_position_steer{i} = plot(app.UIAxes_4,0,0,colors{i});
                
                app.lines_velocity_drive{i} = plot(app.UIAxes_5,0,0,colors{i});
                app.lines_velocity_steer{i} = plot(app.UIAxes_6,0,0,colors{i});
            end
            
            app.encoders_legend_drive = legend(app.UIAxes,names_drive,"AutoUpdate","off");
            app.encoders_legend_steer = legend(app.UIAxes_2,names_steer,"AutoUpdate","off");
            
            app.position_legend_drive = legend(app.UIAxes_3, names_drive,"AutoUpdate","off");
            app.position_legend_steer = legend(app.UIAxes_4,names_steer,'AutoUpdate',"off");
            
            app.velocity_legend_drive = legend(app.UIAxes_5, names_drive,"AutoUpdate","off");
            app.velocity_legend_steer = legend(app.UIAxes_6,names_steer,"AutoUpdate","off");
            
        end


        function resetGraphs(app)
            for i=1:numel(app.lines_encoders_drive)
                app.lines_encoders_drive{i}.XData = app.lines_encoders_drive{i}.XData(1);
                app.lines_encoders_drive{i}.YData = app.lines_encoders_drive{i}.YData(1);
                
                app.lines_encoders_steer{i}.XData = app.lines_encoders_steer{i}.XData(1);
                app.lines_encoders_steer{i}.YData = app.lines_encoders_steer{i}.YData(1);
                
                app.lines_position_drive{i}.XData = app.lines_position_drive{i}.XData(1);
                app.lines_position_drive{i}.YData = app.lines_position_drive{i}.YData(1);
                
                app.lines_position_steer{i}.XData = app.lines_position_steer{i}.XData(1);
                app.lines_position_steer{i}.YData = app.lines_position_steer{i}.YData(1);
                
                app.lines_velocity_drive{i}.XData = app.lines_velocity_drive{i}.XData(1);
                app.lines_velocity_drive{i}.YData = app.lines_velocity_drive{i}.YData(1);
                
                app.lines_velocity_steer{i}.XData = app.lines_velocity_steer{i}.XData(1);
                app.lines_velocity_steer{i}.YData = app.lines_velocity_steer{i}.YData(1);
            end
            
            
        end
        
        
        function updateLamps(app,data)
            if data.state(1) == true
                app.Lamp.Color = 'Green';
            else
                app.Lamp.Color = 'Red';
            end
            
            if data.state(2) == true
                app.Lamp_2.Color = 'Green';
            else
                app.Lamp_2.Color = 'Red';
            end
            
            if data.state(3) == true
                app.Lamp_3.Color = 'Green';
            else
                app.Lamp_3.Color = 'Red';
            end
            
            if data.state(4) == true
                app.Lamp_4.Color = 'Green';
            else
                app.Lamp_4.Color = 'Red';
            end
            
            if data.state(5) == true
                app.Lamp_5.Color = 'Green';
            else
                app.Lamp_5.Color = 'Red';
            end
            
            if data.state(6) == true
                app.Lamp_6.Color = 'Green';
            else
                app.Lamp_6.Color = 'Red';
            end
            
            if data.state(7) == true
                app.Lamp_7.Color = 'Green';
            else
                app.Lamp_7.Color = 'Red';
            end
            
            if data.state(8) == true
                app.Lamp_8.Color = 'Green';
            else
                app.Lamp_8.Color = 'Red';
            end
        end
        
        function updateIndicators(app,data)
            max_rpm_set = 0.229*app.main_app.MaxwheelspeedEditField.Value;
            
            app.BackRightDriveMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(1))/max_rpm_set*100;
            app.FrontRightDriveMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(2))/max_rpm_set*100;
            app.BackLeftDriveMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(3))/max_rpm_set*100;
            app.FrontLeftDriveMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(4))/max_rpm_set*100;
                
            app.BackRightSteerMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(5))/max_rpm_set*100;
            app.FrontRightSteerMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(6))/max_rpm_set*100;
            app.BackLeftSteerMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(7))/max_rpm_set*100;
            app.FrontLeftSteerMotorRPMIndicator.RPM = abs(app.rads2rpm*data.Velocity(8))/max_rpm_set*100;
            
            
        end
        function updateRawData(app,data)
            %Update Edit Fields
            app.Enc1.Value = data.Position(1);
            app.Enc2.Value = data.Position(2);
            app.Enc3.Value = data.Position(3);
            app.Enc4.Value = data.Position(4);
            app.Enc5.Value = data.Position(5);
            app.Enc6.Value = data.Position(6);
            app.Enc7.Value = data.Position(7);
            app.Enc8.Value = data.Position(8);
            
            app.Pos1.Value = data.Radiants(1);
            app.Pos2.Value = data.Radiants(2);
            app.Pos3.Value = data.Radiants(3);
            app.Pos4.Value = data.Radiants(4);
            app.Pos5.Value = data.Radiants(5);
            app.Pos6.Value = data.Radiants(6);
            app.Pos7.Value = data.Radiants(7);
            app.Pos8.Value = data.Radiants(8);
            
            app.Vel1.Value = data.Velocity(1);
            app.Vel2.Value = data.Velocity(2);
            app.Vel3.Value = data.Velocity(3);
            app.Vel4.Value = data.Velocity(4);
            app.Vel5.Value = data.Velocity(5);
            app.Vel6.Value = data.Velocity(6);
            app.Vel7.Value = data.Velocity(7);
            app.Vel8.Value = data.Velocity(8);
            
        end


        function updateGraphs(app, data, time)
            if app.plots_resetted
                for i=1:numel(app.lines_encoders_drive)
                    %Encoder drive wheels
                    if app.EncoderButton.Value == true
                    app.lines_encoders_drive{i}.XData = time;
                    app.lines_encoders_drive{i}.YData = data.Position(i);
                    app.lines_encoders_steer{i}.XData = time;
                    app.lines_encoders_steer{i}.YData = data.Position(i+4);
                    end
                    
                    if app.PositionButton.Value == true
                    app.lines_position_drive{i}.XData = time;
                    app.lines_position_drive{i}.YData = data.Radiants(i);
                    app.lines_position_steer{i}.XData = time;
                    app.lines_position_steer{i}.YData = data.Radiants(i+4);
                    end
                    
                    if app.VelocityButton.Value == true
                    app.lines_velocity_drive{i}.XData = time;
                    app.lines_velocity_drive{i}.YData = data.Velocity(i);
                    app.lines_velocity_steer{i}.XData = time;
                    app.lines_velocity_steer{i}.YData = data.Velocity(i+4);
                    end
                    
                    
                end
            else
                %Encoder drive wheels
                for i=1:numel(app.lines_encoders_drive)
                    if app.EncoderButton.Value == true
                    app.lines_encoders_drive{i}.XData(end+1) = time;
                    app.lines_encoders_drive{i}.YData(end+1) = data.Position(i);
                    app.lines_encoders_steer{i}.XData(end+1) = time;
                    app.lines_encoders_steer{i}.YData(end+1) = data.Position(i+4);
                    end
                    
                    if app.PositionButton.Value == true
                    app.lines_position_drive{i}.XData(end+1) = time;
                    app.lines_position_drive{i}.YData(end+1) = data.Radiants(i);
                    app.lines_position_steer{i}.XData(end+1) = time;
                    app.lines_position_steer{i}.YData(end+1) = data.Radiants(i+4);
                    end
                    
                    if app.VelocityButton.Value == true
                    app.lines_velocity_drive{i}.XData(end+1) = time;
                    app.lines_velocity_drive{i}.YData(end+1) = data.Velocity(i);
                    app.lines_velocity_steer{i}.XData(end+1) = time;
                    app.lines_velocity_steer{i}.YData(end+1) = data.Velocity(i+4);
                    end
                    
                end
                
            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.UIFigure.Name = 'Joints State Data View';
            app.main_app = calling_app;
            app.initializeGraphs()
            app.initialized = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main_app.JointStatesButton.Enable = true;
            app.main_app.joint_state_window = 0;
            delete(app)
            
        end

        % Value changed function: EnableIndicatorsCheckBox
        function EnableIndicatorsCheckBoxValueChanged(app, event)
            value = app.EnableIndicatorsCheckBox.Value;
            if value == false
                app.lamp_update = 1;
            end
        end

        % Value changed function: ShowGraphsCheckBox
        function ShowGraphsCheckBoxValueChanged(app, event)
            value = app.ShowGraphsCheckBox.Value;
            app.plots_resetted = true;
            
            
            if value == true
                app.resetGraphs();
                app.initial_plot_time = app.last_message.Header.Stamp;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.RPMIndicatorsPanel = uipanel(app.SummaryTab);
            app.RPMIndicatorsPanel.Title = 'RPM Indicators';
            app.BackRightDriveMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.BackRightDriveMotorRPMIndicatorLabel.Text = 'Back-Right-Drive-Motor';
            app.BackRightDriveMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.FrontRightDriveMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.FrontRightDriveMotorRPMIndicatorLabel.Text = 'Front-Right-Drive-Motor';
            app.FrontRightDriveMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.BackLeftDriveMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.BackLeftDriveMotorRPMIndicatorLabel.Text = 'Back-Left-Drive-Motor';
            app.BackLeftDriveMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.FrontLeftDriveMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.FrontLeftDriveMotorRPMIndicatorLabel.Text = 'Front-Left-Drive-Motor';
            app.FrontLeftDriveMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.BackRightSteerMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.BackRightSteerMotorRPMIndicatorLabel.Text = 'Back-Right-Steer-Motor';
            app.BackRightSteerMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.FrontRightSteerMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.FrontRightSteerMotorRPMIndicatorLabel.Text = 'Front-Right-Steer-Motor';
            app.FrontRightSteerMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.BackLeftSteerMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.BackLeftSteerMotorRPMIndicatorLabel.Text = 'Back-Left-Steer-Motor';
            app.BackLeftSteerMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.FrontLeftSteerMotorRPMIndicatorLabel = uilabel(app.RPMIndicatorsPanel);
            app.FrontLeftSteerMotorRPMIndicatorLabel.Text = 'Front-Left-Steer-Motor';
            app.FrontLeftSteerMotorRPMIndicator = uiaerorpm(app.RPMIndicatorsPanel);
            app.Lamp = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_2 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_3 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_4 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_5 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_6 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_7 = uilamp(app.RPMIndicatorsPanel);
            app.Lamp_8 = uilamp(app.RPMIndicatorsPanel);
            app.RawDataPanel = uipanel(app.SummaryTab);
            app.RawDataPanel.Title = 'Raw Data';
            app.EncoderPanel = uipanel(app.RawDataPanel);
            app.EncoderPanel.Title = 'Encoder';
            app.Enc1 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc1.Editable = 'off';
            app.Enc2 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc2.Editable = 'off';
            app.Enc3 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc3.Editable = 'off';
            app.Enc4 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc4.Editable = 'off';
            app.Enc5 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc5.Editable = 'off';
            app.Enc6 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc6.Editable = 'off';
            app.Enc7 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc7.Editable = 'off';
            app.Enc8 = uieditfield(app.EncoderPanel, 'numeric');
            app.Enc8.Editable = 'off';
            app.BRDMLabel = uilabel(app.RawDataPanel);
            app.BRDMLabel.Text = 'BRDM';
            app.FRDMLabel = uilabel(app.RawDataPanel);
            app.FRDMLabel.Text = 'FRDM';
            app.BLDMLabel = uilabel(app.RawDataPanel);
            app.BLDMLabel.Text = 'BLDM';
            app.FLDMLabel = uilabel(app.RawDataPanel);
            app.FLDMLabel.Text = 'FLDM';
            app.BRSMLabel = uilabel(app.RawDataPanel);
            app.BRSMLabel.Text = 'BRSM';
            app.FRSMLabel = uilabel(app.RawDataPanel);
            app.FRSMLabel.Text = 'FRSM';
            app.BLSMLabel = uilabel(app.RawDataPanel);
            app.BLSMLabel.Text = 'BLSM';
            app.FLSMLabel = uilabel(app.RawDataPanel);
            app.FLSMLabel.Text = 'FLSM';
            app.PositionPanel = uipanel(app.RawDataPanel);
            app.PositionPanel.Title = 'Position';
            app.Pos1 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos1.Editable = 'off';
            app.Pos2 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos2.Editable = 'off';
            app.Pos3 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos3.Editable = 'off';
            app.Pos4 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos4.Editable = 'off';
            app.Pos5 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos5.Editable = 'off';
            app.Pos6 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos6.Editable = 'off';
            app.Pos7 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos7.Editable = 'off';
            app.Pos8 = uieditfield(app.PositionPanel, 'numeric');
            app.Pos8.Editable = 'off';
            app.VelocityPanel = uipanel(app.RawDataPanel);
            app.VelocityPanel.Title = 'Velocity';
            app.Vel1 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel1.Editable = 'off';
            app.Vel2 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel2.Editable = 'off';
            app.Vel3 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel3.Editable = 'off';
            app.Vel4 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel4.Editable = 'off';
            app.Vel5 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel5.Editable = 'off';
            app.Vel6 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel6.Editable = 'off';
            app.Vel7 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel7.Editable = 'off';
            app.Vel8 = uieditfield(app.VelocityPanel, 'numeric');
            app.Vel8.Editable = 'off';
            app.OptionsPanel = uipanel(app.SummaryTab);
            app.OptionsPanel.Title = 'Options';
            app.EnableIndicatorsCheckBox = uicheckbox(app.OptionsPanel);
            app.EnableIndicatorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableIndicatorsCheckBoxValueChanged, true);
            app.EnableIndicatorsCheckBox.Text = 'Enable Indicators';
            app.ShowGraphsCheckBox = uicheckbox(app.OptionsPanel);
            app.ShowGraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphsCheckBoxValueChanged, true);
            app.ShowGraphsCheckBox.Text = 'Show Graphs';
            app.DatatoShowButtonGroup = uibuttongroup(app.SummaryTab);
            app.DatatoShowButtonGroup.Title = 'Data to Show';
            app.EncoderButton = uiradiobutton(app.DatatoShowButtonGroup);
            app.EncoderButton.Text = 'Encoder';
            app.EncoderButton.Value = true;
            app.PositionButton = uiradiobutton(app.DatatoShowButtonGroup);
            app.PositionButton.Text = 'Position';
            app.VelocityButton = uiradiobutton(app.DatatoShowButtonGroup);
            app.VelocityButton.Text = 'Velocity';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.TabGroup2 = uitabgroup(app.GraphTab);
            app.EncodersTab = uitab(app.TabGroup2);
            app.EncodersTab.Title = 'Encoders';
            app.DriveWheelsPanel = uipanel(app.EncodersTab);
            app.DriveWheelsPanel.Title = 'Drive Wheels';
            app.UIAxes = uiaxes(app.DriveWheelsPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'ticks')
            app.SteerWheelsPanel = uipanel(app.EncodersTab);
            app.SteerWheelsPanel.Title = 'Steer Wheels';
            app.UIAxes_2 = uiaxes(app.SteerWheelsPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'ticks')
            app.PositionTab = uitab(app.TabGroup2);
            app.PositionTab.Title = 'Position';
            app.DriveWheelsPanel_2 = uipanel(app.PositionTab);
            app.DriveWheelsPanel_2.Title = 'Drive Wheels';
            app.UIAxes_3 = uiaxes(app.DriveWheelsPanel_2);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'rad')
            app.SteerWheelsPanel_2 = uipanel(app.PositionTab);
            app.SteerWheelsPanel_2.Title = 'Steer Wheels';
            app.UIAxes_4 = uiaxes(app.SteerWheelsPanel_2);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, 'rad')
            app.VelocityTab = uitab(app.TabGroup2);
            app.VelocityTab.Title = 'Velocity';
            app.DriveWheelsPanel_3 = uipanel(app.VelocityTab);
            app.DriveWheelsPanel_3.Title = 'Drive Wheels';
            app.UIAxes_5 = uiaxes(app.DriveWheelsPanel_3);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, 'rad/s')
            app.SteerWheelsPanel_3 = uipanel(app.VelocityTab);
            app.SteerWheelsPanel_3.Title = 'Steer Wheels';
            app.UIAxes_6 = uiaxes(app.SteerWheelsPanel_3);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, 'rad/s')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = joint_states_visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end