classdef magnetic_field_visulization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        OptionsPanel       matlab.ui.container.Panel
        ShowGraphCheckBox  matlab.ui.control.CheckBox
        RawDataPanel       matlab.ui.container.Panel
        XEditFieldLabel    matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        YEditFieldLabel    matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        ZEditFieldLabel    matlab.ui.control.Label
        ZEditField         matlab.ui.control.NumericEditField
        GraphPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        initialized = false; % Description
        lines;
        main_app;
        last_data;
        
        mag_legend;
        plot_resetted;
        plot_initialized;
        initial_plot_time;
        
        
    end
    
    methods (Access = private)
        
        function initializeGraph(app)
            hold(app.UIAxes,'on')
            app.lines = cell(1,3);
            app.lines{1} = plot(app.UIAxes,0,0,'-r');
            app.lines{2} = plot(app.UIAxes,0,0,'-b');
            app.lines{3} = plot(app.UIAxes,0,0,'-g');
            app.mag_legend = legend(app.UIAxes,{'X','Y','Z'},"AutoUpdate","off");
            app.plot_initialized = true;
            
        end
        function resetPlot(app)
            for i=1:numel(app.lines)
                app.lines{i}.XData = app.lines{i}.XData(1);
                app.lines{i}.YData = app.lines{i}.YData(1);
            end
        end
        function updateGraph(app,msg,time)
            tmp = [msg.MagneticField_.X, msg.MagneticField_.Y, msg.MagneticField_.Z];
            
            for i=1:numel(app.lines)
                if app.plot_resetted
                    app.lines{i}.XData = time;
                    app.lines{i}.YData = tmp(i);
                else
                    app.lines{i}.XData(end+1) = time;
                    app.lines{i}.YData(end+1) = tmp(i);
                end
            end
        end


        function updateRawData(app,msg)
            app.XEditField.Value = msg.MagneticField_.X;
            app.YEditField.Value = msg.MagneticField_.Y;
            app.ZEditField.Value = msg.MagneticField_.Z;
            
        end
    end
    
    methods (Access = public)
        function updatePlots(app)
            data = app.main_app.collected_data.magnetic_field{end};
            app.last_data = data;
            
            app.updateRawData(data)
            
            if app.ShowGraphCheckBox.Value == true
                time = data.Header.Stamp - app.initial_plot_time;
                time1 = time.Sec + time.Nsec/1e9;
                
                app.updateGraph(data,time1)
                if app.plot_resetted
                    app.plot_resetted = false;
                    
                end
            end
        end
        
        function flag = isInitialized(app)
            flag = app.initialized;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.UIFigure.Name = 'Magnetic Field Data View';
            app.main_app = calling_app;
            app.initializeGraph()
            app.initialized = true;
            
        end

        % Value changed function: ShowGraphCheckBox
        function ShowGraphCheckBoxValueChanged(app, event)
            value = app.ShowGraphCheckBox.Value;
            
            app.plot_resetted = true;
            
            
            if value == true
                app.resetPlot()
                app.initial_plot_time = app.last_data.Header.Stamp;
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main_app.MagneticFieldButton.Enable = true;
            app.main_app.magnetic_field_window = 0;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ShowGraphCheckBox = uicheckbox(app.OptionsPanel);
            app.ShowGraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphCheckBoxValueChanged, true);
            app.ShowGraphCheckBox.Text = 'Show Graph';
            app.RawDataPanel = uipanel(app.UIFigure);
            app.RawDataPanel.Title = 'Raw Data';
            app.XEditFieldLabel = uilabel(app.RawDataPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RawDataPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.RawDataPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RawDataPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.RawDataPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RawDataPanel, 'numeric');
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphPanel);
            title(app.UIAxes, 'Magnetic Field')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magnetic_field_visulization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end