classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        IpFinderPanel                   matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        DiscoverButton                  matlab.ui.control.Button
        GatewayEditFieldLabel           matlab.ui.control.Label
        GatewayEditField                matlab.ui.control.EditField
        ROSGeneralInformationsPanel     matlab.ui.container.Panel
        TopicsdetectedTextAreaLabel     matlab.ui.control.Label
        TopicsdetectedTextArea          matlab.ui.control.TextArea
        NodesdetectedTextAreaLabel      matlab.ui.control.Label
        NodesdetectedTextArea           matlab.ui.control.TextArea
        UpdateButton                    matlab.ui.control.Button
        InitializeCommButton            matlab.ui.control.Button
        NetworkandRemoteRoverSetupPanel  matlab.ui.container.Panel
        ChangeNetworkButton             matlab.ui.control.Button
        PingDeviceButton                matlab.ui.control.Button
        RemoteStartUpPanel              matlab.ui.container.Panel
        ScriptloaderButton              matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        SendandExecuteButton            matlab.ui.control.Button
        EditScriptButton                matlab.ui.control.Button
        SSHPanel                        matlab.ui.container.Panel
        OpenSSHButton                   matlab.ui.control.Button
        SettingsButton                  matlab.ui.control.Button
        ShutdownDeviceButton            matlab.ui.control.Button
        ConnectionstatusLampLabel       matlab.ui.control.Label
        ConnectionstatusLamp            matlab.ui.control.Lamp
        RemoteDesktopPanel              matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        PathtoVNCExecutableLabel        matlab.ui.control.Label
        PathtoVNCExecutableEditField    matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        ApplyButton_2                   matlab.ui.control.Button
        AquisitionPanel                 matlab.ui.container.Panel
        StartCollectingdatasButton      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SelectedTopicsTextAreaLabel     matlab.ui.control.Label
        SelectedTopicsTextArea          matlab.ui.control.TextArea
        ChoosedatatoaquireButton        matlab.ui.control.Button
        TsEditFieldLabel                matlab.ui.control.Label
        TsEditField                     matlab.ui.control.NumericEditField
        ResetDataButton                 matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        OverrideDefaultParametersPanel  matlab.ui.container.Panel
        OverrideButton                  matlab.ui.control.Button
        TurningradiusSliderLabel        matlab.ui.control.Label
        TurningradiusSlider             matlab.ui.control.Slider
        MaxwheelsppedSliderLabel        matlab.ui.control.Label
        MaxwheelsppedSlider             matlab.ui.control.Slider
        GeneralOptionsPanel             matlab.ui.container.Panel
        EnableDisableRemotecontrolfromcontrollerCheckBox  matlab.ui.control.CheckBox
        EnableDisabletorqueCheckBox     matlab.ui.control.CheckBox
        EnableDisablewritetofiledatasCheckBox  matlab.ui.control.CheckBox
        SelectpathButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        DriveOptionsPanel               matlab.ui.container.Panel
        MaxwheelspeedEditFieldLabel     matlab.ui.control.Label
        MaxwheelspeedEditField          matlab.ui.control.NumericEditField
        MaxTurningRadiusEditFieldLabel  matlab.ui.control.Label
        MaxTurningRadiusEditField       matlab.ui.control.NumericEditField
        maxRPMEditFieldLabel            matlab.ui.control.Label
        maxRPMEditField                 matlab.ui.control.EditField
        maxXSpeedEditFieldLabel         matlab.ui.control.Label
        maxXSpeedEditField              matlab.ui.control.EditField
        RemoteControlTab                matlab.ui.container.Tab
        EnableremotecontrolCheckBox     matlab.ui.control.CheckBox
        ModeSelectionButtonGroup        matlab.ui.container.ButtonGroup
        DriveMode1Button                matlab.ui.control.RadioButton
        DriveMode2Button                matlab.ui.control.RadioButton
        DriveMode3Button                matlab.ui.control.RadioButton
        DriveMode4Button                matlab.ui.control.RadioButton
        SummaryTab                      matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        HeadingIndicatorLabel           matlab.ui.control.Label
        HeadingIndicator                Aero.ui.control.HeadingIndicator
        PositionPanel                   matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        YawEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        RollEditField                   matlab.ui.control.NumericEditField
        SpeedPanel                      matlab.ui.container.Panel
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        YawEditField_2Label             matlab.ui.control.Label
        YawEditField_2                  matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        PitchEditField_2Label           matlab.ui.control.Label
        PitchEditField_2                matlab.ui.control.NumericEditField
        RollEditField_2Label            matlab.ui.control.Label
        RollEditField_2                 matlab.ui.control.NumericEditField
        AlarmPanel                      matlab.ui.container.Panel
        AlarmOptionsButtonGroup         matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        OnButton                        matlab.ui.control.RadioButton
        LowBatteryButton                matlab.ui.control.RadioButton
        ErrorButton                     matlab.ui.control.RadioButton
        Button1                         matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        AlarmButton                     matlab.ui.control.Button
        OdometricYawEditFieldLabel      matlab.ui.control.Label
        OdometricYawEditField           matlab.ui.control.NumericEditField
        OdometricYawSpeedEditFieldLabel  matlab.ui.control.Label
        OdometricYawSpeedEditField      matlab.ui.control.NumericEditField
        VisualizingTab                  matlab.ui.container.Tab
        WindowSelectionPanel            matlab.ui.container.Panel
        JointStatesButton               matlab.ui.control.Button
        UpdatePeriodEditFieldLabel      matlab.ui.control.Label
        UpdatePeriodEditField           matlab.ui.control.NumericEditField
        ImuButton                       matlab.ui.control.Button
        MotorTemperatureButton          matlab.ui.control.Button
        MotorCurrentButton              matlab.ui.control.Button
        MagneticFieldButton             matlab.ui.control.Button
        CameraPanel                     matlab.ui.container.Panel
        CaptureButton                   matlab.ui.control.Button
        BrowseButton_2                  matlab.ui.control.Button
        SaveLocationLabel               matlab.ui.control.Label
        SaveLocationEditField           matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        CameraImage                     matlab.ui.control.Image
        ShowButton                      matlab.ui.control.Button
        UIAxes7                         matlab.ui.control.UIAxes
        TeleopTab                       matlab.ui.container.Tab
        EnableTeleopCheckBox            matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        InstructionsLabel               matlab.ui.control.Label
        OperatingModeButtonGroup        matlab.ui.container.ButtonGroup
        AckermanSteerButton             matlab.ui.control.RadioButton
        ParallelDriveButton             matlab.ui.control.RadioButton
        LateralDriveButton              matlab.ui.control.RadioButton
        InPlaceRotationButton           matlab.ui.control.RadioButton
        CommandedPanel                  matlab.ui.container.Panel
        AckermannPanel                  matlab.ui.container.Panel
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        x_dotEditFieldLabel             matlab.ui.control.Label
        x_dotEditField                  matlab.ui.control.NumericEditField
        theta_dotEditFieldLabel         matlab.ui.control.Label
        theta_dotEditField              matlab.ui.control.NumericEditField
        EmergencyStopButton             matlab.ui.control.Button
        EmergencyUnlockButton           matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        loop_check = false;
        motor_torque_pub;
        motor_torque_msg;
        alarm_pub;
        alarm_msg;
        cmd_vel_pub;
        cmd_vel_msg;
        emergency_teleop_triggered = false;
        
        
        odo_subscriber;
        joint_state_subscriber;
        imu_subscriber;
        motor_current_subscriber;
        motor_temperature_subscriber
        magnetic_field_subscriber;
        sensor_states_subscriber;
        camera_subscriber
        
        odo_file;
        joint_file;
        
        odo_data;
        joint_data;
        temperature_data;
        current_data;
        selected_ip;
         % Description
        selected_topics_aquisition;
        
        timer_var; %for aquisition porpouse
        timer_plot; %For plot update
        collected_data;
        
        imu_window
        joint_state_window;
        motor_temp_window;
        motor_curr_window;
        magnetic_field_window;
        
        
        motor_scale_factor % Description
        
        
        wheel_radius = 85/1000;
        %teleop parameters
        up_key = 0;
        left_key = 0;
        
        %Ackermann 1
        delta_x = 0.01;
        delta_R = 0.2;


    end
    
    properties (Access = private)
        topics_detected;
        nodes_detected;
        data_saved = true;
    end


    methods (Access = private)
        
        function initialize(app)
            app.odo_subscriber = rossubscriber("/odom");% Description
            app.joint_state_subscriber = rossubscriber("/joint_states");
            app.imu_subscriber = rossubscriber("/imu");
            app.motor_current_subscriber = rossubscriber("/Motor_current");
            app.motor_temperature_subscriber = rossubscriber("/Motor_temperature");
            app.magnetic_field_subscriber = rossubscriber("/magnetic_field");
            app.sensor_states_subscriber = rossubscriber("/sensor_state");
            app.cmd_vel_pub = rospublisher('/cmd_vel');
            app.cmd_vel_msg = rosmessage(app.cmd_vel_pub);


            if any(strcmp(app.topics_detected,'/raspicam_node/image/compressed'))%...
                    %&& strcmp(class(app.camera_subscriber),'ros.Subscriber')
                app.camera_subscriber = rossubscriber("/raspicam_node/image/compressed");
                app.CaptureButton.Enable = true;
                %app.SaveButton.Enable = true;
                app.BrowseButton_2.Enable = true;
                app.SaveLocationEditField.Enable = true;
                app.SaveLocationEditField.Value = strcat(pwd,'/data/images');
            end
        
        
            app.selected_ip = '';


            app.MaxwheelspeedEditField.Value = double(rosparam("get","/rover_ws/max_wheel_speed"));
            app.EnableDisabletorqueCheckBox.Value = true;
            app.MaxTurningRadiusEditField.Value = double(rosparam("get","/rover_ws/max_turn_radius"));
            app.EnableDisablewritetofiledatasCheckBox.Value = false;


            %% Setup publishers
            app.motor_torque_pub = rospublisher("/motor_power");
            app.motor_torque_msg = rosmessage("std_msgs/Bool");
            
            app.alarm_pub = rospublisher("/sound");
            app.alarm_msg = rosmessage(app.alarm_pub);
            
            %UpdateReadings(app)
            
            value = app.MaxwheelspeedEditField.Value;
            app.maxRPMEditField.Value = num2str(value*0.229);
            app.maxXSpeedEditField.Value = num2str(value*0.2292*2*pi/60*app.wheel_radius);
            
        end
        
        function flag = checkCoreRunning(app, override)
            try
                tmp = rostopic('list');
                app.ConnectionstatusLamp.Color = 'Green';
                flag = true;
            catch ME
                app.ConnectionstatusLamp.Color = 'Red';
                flag = false;
                if ~exist('override',"var")
                    answer = uiconfirm(app.UIFigure, 'No ROS master running found. Do you want to start the ROS core?','Error',"Icon",'error');
                    if strcmp(answer, 'OK')
                        %app.UIFigure.
                        app_new = choose_network(app);
                        waitfor(app_new)
                        flag = app.checkCoreRunning('yes');
                    end
                        
                end
            end
            
        end
        
        function updateTopics(app)
            try
                topics = rostopic('list');
                nodes = rosnode('list');
                for i=1:size(topics,1)
                    if i ==1
                        app.TopicsdetectedTextArea.Value = topics{i};
                    else
                        app.TopicsdetectedTextArea.Value = vertcat(app.TopicsdetectedTextArea.Value, topics{i});
                    end
                end
                
                if numel(topics)==1
                    app.InitializeCommButton.Enable = false;
                else
                    app.InitializeCommButton.Enable = true;
                end
                app.topics_detected = topics;
            
                for i=1:size(nodes,2)
                    if i ==1
                        app.NodesdetectedTextArea.Value = nodes{i};
                    
                    else
                        app.NodesdetectedTextArea.Value = vertcat(app.NodesdetectedTextArea.Value,nodes{i});
                    end
                
                end
                app.nodes_detected = nodes;
            catch ME
                app.TopicsdetectedTextArea.Value = "";
                app.NodesdetectedTextArea.Value = "";
                
            end
            
        end
        
        function reset(app)
            %Initialize collected data
            app.collected_data.odometry = {};
            app.collected_data.imu = {};
            app.collected_data.joint_states = {};
            app.collected_data.motor_temperature = {};
            app.collected_data.motor_current = {};
            app.collected_data.magnetic_field = {};
            app.collected_data.sensor_states = {};
            
            
            app.data_saved = true;
        end
        
        function initialVisibility(app)
            app.MaxwheelsppedSlider.Enable = false;
            app.TurningradiusSlider.Enable = false;
            app.SendandExecuteButton.Enable = false;
            app.CaptureButton.Enable = false;
            
            app.SaveButton.Enable = false;
            app.BrowseButton_2.Enable = false;
            app.SaveLocationEditField.Enable = false;
            app.ShowButton.Enable = false;
            app.SaveButton_2.Enable = false;
            
            
        end
        
        function sample(app,fake_in)
            %Odometry
            if any(strcmp(app.SelectedTopicsTextArea.Value,'odometry'))
                app.collected_data.odometry{end+1} = receive(app.odo_subscriber);
            end
            if any(strcmp(app.SelectedTopicsTextArea.Value,'imu'))
                app.collected_data.imu{end+1} = receive(app.imu_subscriber);
            end
            
            if any(strcmp(app.SelectedTopicsTextArea.Value,'joint_states'))
                app.collected_data.joint_states{end+1} = receive(app.joint_state_subscriber);
            end
            
            if any(strcmp(app.SelectedTopicsTextArea.Value,'motor_temperature'))
                app.collected_data.motor_temperature{end+1} = receive(app.motor_temperature_subscriber);
            end
            
            if any(strcmp(app.SelectedTopicsTextArea.Value,'motor_current'))
                app.collected_data.motor_current{end+1} = receive(app.motor_current_subscriber);
            end
            
            if any(strcmp(app.SelectedTopicsTextArea.Value,'magnetic_field'))
                app.collected_data.magnetic_field{end+1} = receive(app.magnetic_field_subscriber);
            end
            
            if any(strcmp(app.SelectedTopicsTextArea.Value,'sensor_states'))
                app.collected_data.sensor_states{end+1} = receive(app.sensor_states_subscriber);
            end
            
            %disp(app.collected_data.magnetic_field)
            
        end
        
        function updatePlots(app,fake_in)
            %disp(class(app.motor_temp_window))
            app.updateSummaryPanel()
            
            if strcmp(class(app.joint_state_window),'joint_states_visualization')
                if app.joint_state_window.isInitialized()
                    app.joint_state_window.updatePlots()
                end
            end
            
            if strcmp(class(app.motor_temp_window), 'temperature_visualization')
                disp('I want to plot!')
                if app.motor_temp_window.isInitialized()
                    disp('Initialized!')
                    app.motor_temp_window.updatePlots()
                end
            end
            
            if strcmp(class(app.imu_window),'imu_visualization')
                if app.imu_window.isInitialized()
                    app.imu_window.updatePlots()
                end
            end
            
            if strcmp(class(app.motor_curr_window),'current_visualization')
                if app.motor_curr_window.isInitialized()
                    app.motor_curr_window.updatePlots()
                end
            end
            %strcmp(class(app.magnetic_field_window),'magnetic_field_visulization')
            if strcmp(class(app.magnetic_field_window),'magnetic_field_visulization')
                disp(app.magnetic_field_window.isInitialized())
                if app.magnetic_field_window.isInitialized()
                    app.magnetic_field_window.updatePlots()
                end
            end
            
        end
        
        function updateSummaryPanel(app)
            if ~isempty(app.collected_data.odometry)
                data_odo = app.collected_data.odometry{end};
%                 orientation_odo = 2*atan2(app.odo_data.Pose.Pose.Orientation.Z,app.odo_data.Pose.Pose.Orientation.W);
%                 app.XEditField.Value = data_odo.Pose.Pose.Position.X;
%                 app.YEditField.Value = data_odo.Pose.Pose.Position.Y;
%                 app.XEditField_2.Value = data_odo.Twist.Twist.Linear.X;
%                 app.YEditField_2.Value = data_odo.Twist.Twist.Linear.Y;
%                 
%                 app.HeadingIndicator.Heading = rad2deg(orientation_odo);
%                 app.OdometricYawEditField.Value = wrapToPi(orientation_odo);
%                 app.OdometricYawSpeedEditField.Value = data_odo.Twist.Twist.Angular.Z;
                
                
                %Update Graph
                plot(app.UIAxes6, data_odo.Pose.Pose.Position.X, data_odo.Pose.Pose.Position.Y, '-or');
                
                
            end
            
            
            if ~isempty(app.collected_data.imu)
                data_imu = app.collected_data.imu{end};
                quat = [data_imu.Orientation.X, data_imu.Orientation.Y, data_imu.Orientation.Z, data_imu.Orientation.W];
                
                [yaw,pitch,roll] = quat2angle(quat);
                app.YawEditField.Value = yaw;
                app.PitchEditField.Value = pitch;
                app.RollEditField.Value = roll;
                
                app.YawEditField_2.Value = data_imu.AngularVelocity.Z;
                app.PitchEditField_2.Value = data_imu.AngularVelocity.X;
                app.RollEditField_2.Value = data_imu.AngularVelocity.Y;
            
            end
%                 %Write to odometry file
%                 fprintf(app.odo_file,'%d %6.3f \t %6.3f \t %6.3f \t %6.3f \n', time, app.odo_data.Pose.Pose.Position.X, ...
%                    app.odo_data.Pose.Pose.Position.Y, app.odo_data.Pose.Pose.Position.Z,orientation);
%                 
%                %Write to joint state file
%                 fprintf(app.joint_file,'%d %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f \t %6.3f', ...
%                     app.joint_data.Header.Stamp.Nsec/(1e9), app.joint_data.Radiants(1), ...
%                     app.joint_data.Radiants(2), app.joint_data.Radiants(3), app.joint_data.Radiants(4), ...
%                     app.joint_data.Radiants(5),app.joint_data.Radiants(6), app.joint_data.Radiants(7), app.joint_data.Radiants(8), ...
%                     app.joint_data.Velocity(1), ...
%                     app.joint_data.Velocity(2), app.joint_data.Velocity(3), app.joint_data.Velocity(4), ...
%                     app.joint_data.Velocity(5),app.joint_data.Velocity(6), app.joint_data.Velocity(7), app.joint_data.Velocity(8));
%                 
%                 
%                 
%                 %Compiling numeric fields
%                 app.XEditField.Value = app.odo_data.Pose.Pose.Position.X;
%                 app.YEditField.Value = app.odo_data.Pose.Pose.Position.Y;
%                 app.ZEditField.Value = app.odo_data.Pose.Pose.Position.Z;
%                 app.YawEditField.Value = orientation;
%                 app.XEditField_2.Value = app.odo_data.Twist.Twist.Linear.X;
%                 app.YEditField_2.Value = app.odo_data.Twist.Twist.Linear.Y;
%                 app.ZEditField_2.Value = app.odo_data.Twist.Twist.Linear.Z;
%                 app.YawEditField_2.Value = app.odo_data.Twist.Twist.Angular.Z;
            
        end
        
        function udpateVisualizatioPanel(app,flag)
            if flag == true
                if any(strcmp(app.SelectedTopicsTextArea.Value,'imu'))
                    app.ImuButton.Enable = true;
                end
                
                if any(strcmp(app.SelectedTopicsTextArea.Value,'joint_states'))
                    app.JointStatesButton.Enable = true;
                end
                
                if any(strcmp(app.SelectedTopicsTextArea.Value,'motor_temperature'))
                    app.MotorTemperatureButton.Enable = true;
                end
                
                if any(strcmp(app.SelectedTopicsTextArea.Value,'motor_current'))
                    app.MotorCurrentButton.Enable = true;
                end
                
                if any(strcmp(app.SelectedTopicsTextArea.Value,'magnetic_field'))
                    app.MagneticFieldButton.Enable = true;
                end
                
            else
                app.ImuButton.Enable = false;
                app.JointStatesButton.Enable = false;
                app.MotorTemperatureButton.Enable = false;
                app.MotorCurrentButton.Enable = false;
                app.MagneticFieldButton.Enable = false;
            end
        end


        function saveData(app)
            id_aquisition = datestr(datetime('now'),'yyyy_mm_dd__HH_MM_ss');
            
            folder_to_save = strcat(app.EditField.Value,'\aquisitions\',id_aquisition);
            mkdir(folder_to_save)
            
            tmp = app.collected_data;
            save(strcat(folder_to_save,'\original.mat'),'tmp');
            folder_names = {'odometry','joint_states','imu','motor_current','motor_temperature','magnetic_field'};
            file_name = 'data.dat';
            for i=1:numel(folder_names)
                if any(strcmp(app.SelectedTopicsTextArea.Value,folder_names{i}))
                    mkdir(strcat(folder_to_save,'\',folder_names{i}))
                    
                    tmp = app.collected_data.(folder_names{i});
                    save(strcat(folder_to_save,'\',folder_names{i},'\original.mat'),"tmp");
                    
                    %Open file
                    f = fopen(strcat(folder_to_save,'\',folder_names{i},'\',file_name),'w');
                    
                    for j=1:numel(app.collected_data.(folder_names{i}))
                        fprintf(f,app.StringToWrite(folder_names{i}, j));
                    end
                    fclose(f);
                end
                    
                    
            end


            app.data_saved = true;
            %app.SaveButton_2.Enable = false;
        end
        
        function out_str = StringToWrite(app,name,line)
            if strcmp(name,'odometry')
                data = app.collected_data.odometry{line};
                if line==1
                    string_write = ['Sec\tNsec\tPosX\tPosY\tPosZ\tOrientX\t' ...
                        'OrientY\tOrientZ\tOrientW\tVelX\tVelY\tVelZ\tAngVelX\t'...
                        'AngVelY\tAngVelZ\n'];
                else
                    string_write = sprintf(['%11d\t%11d' ...
                    '\t%5.5f\t%5.5f\t%5.5f\t' ...
                    '%5.5f\t%5.5f\t%5.5f\t%5.5f\t' ...
                    '%5.5f\t%5.5f\t%5.5f\t' ...
                    '%5.5f\t%5.5f\t%5.5f\n'],data.Header.Stamp.Sec,data.Header.Stamp.Nsec, ...
                    data.Pose.Pose.Position.X,data.Pose.Pose.Position.Y,data.Pose.Pose.Position.Z, ...
                    data.Pose.Pose.Orientation.X,data.Pose.Pose.Orientation.Y,data.Pose.Pose.Orientation.Z,data.Pose.Pose.Orientation.W, ...
                    data.Twist.Twist.Linear.X,data.Twist.Twist.Linear.Y,data.Twist.Twist.Linear.Z, ...
                    data.Twist.Twist.Angular.X,data.Twist.Twist.Angular.Y,data.Twist.Twist.Angular.Z);
                end
            elseif strcmp(name,'imu')
                data = app.collected_data.imu{line};
                if line==1
                    string_write = ['Sec\tNsec\t'...
                        'OrientX\tOrientY\tOrientZ\tOrientW\t'...
                        'AngVelX\tAngVelY\tAngVelZ\t'...
                        'AccX\tAccY\tAccZ\n'];
                    
                else
                    string_write = sprintf(['%11d\t%11d\t'...
                        '%5.5f\t%5.5f\t%5.5f\t%5.5f\t'...
                        '%5.5f\t%5.5f\t%5.5f\t'...
                        '%5.5f\t%5.5f\t%5.5f\n'],data.Header.Stamp.Sec,data.Header.Stamp.Nsec, ...
                        data.Orientation.X,data.Orientation.Y,data.Orientation.Z,data.Orientation.W,...
                        data.AngularVelocity.X,data.AngularVelocity.Y,data.AngularVelocity.Z, ...
                        data.LinearAcceleration.X,data.LinearAcceleration.Y,data.LinearAcceleration.Z);
                end
                
                
            elseif strcmp(name,'joint_states')
                data = app.collected_data.joint_states{line};
                Enc_pack = mat2cell(data.Position',1,ones(1,numel(data.Position)));
                Rad_Pack = mat2cell(data.Radiants',1,ones(1,numel(data.Radiants)));
                Vel_Pack = mat2cell(data.Velocity',1,ones(1,numel(data.Velocity)));
                if line==1
                    string_write = ['Sec\tNsec\t'...
                        'PosBRWF\tPosFRWF\tPosBLWF\tPosFLWF\t'...
                        'PosBRWS\tPosFRWS\tPosBLWS\tPosFLWS\t'...
                        'RotBRWF\tRotFRWF\tRotBLWF\tRotFLWF\t'...
                        'RotBRWS\tRotFRWS\tRotBLWS\tRotFLWS\t'...
                        'VelBRWF\tVelFRWF\tVelBLWF\tVelFLWF\t'...
                        'VelBRWS\tVelFRWS\tVelBLWS\tVelFLWS\n'];
                else
                    string_write = sprintf(['%11d\t%11d\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\n'], data.Header.Stamp.Sec,data.Header.Stamp.Nsec, ...
                        Enc_pack{:}, Rad_Pack{:},Vel_Pack{:});
                end
                
            elseif strcmp(name,'motor_current')
                data = app.collected_data.motor_current{line};
                tmp = [data.BRWFDynamixelCurrent, data.FRWFDynamixelCurrent, data.BLWFDynamixelCurrent, data.FLWFDynamixelCurrent,...
                    data.BRWSDynamixelCurrent, data.FRWSDynamixelCurrent, data.BLWSDynamixelCurrent, data.FLWSDynamixelCurrent];
                current_pack = mat2cell(tmp,1,ones(1,numel(tmp)));
                
                if line == 1
                    string_write = ['Sec\tNsec\t'...
                        'BRWF\tFRWF\tBLWF\tFLWF\t'...
                        'BRWS\tFRWS\tBLWS\tFLWS\n'];
                else
                    string_write = sprintf(['%11d\t%11d\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                        '%9.9f\t%9.9f\t%9.9f\t%9.9f\n'], data.Header.Stamp.Sec, data.Header.Stamp.Nsec, current_pack{:});
                end
                
            elseif strcmp(name,'motor_temperature')
                    data = app.collected_data.motor_temperature{line};
                    tmp = [data.BRWFDynamixelTemperature, data.FRWFDynamixelTemperature, data.BLWFDynamixelTemperature, data.FLWFDynamixelTemperature,...
                    data.BRWSDynamixelTemperature, data.FRWSDynamixelTemperature, data.BLWSDynamixelTemperature, data.FLWSDynamixelTemperature];
                    temp_pack = mat2cell(tmp,1,ones(1,numel(tmp)));
                    if line == 1
                    string_write = ['Sec\tNsec\t'...
                        'BRWF\tFRWF\tBLWF\tFLWF\t'...
                        'BRWS\tFRWS\tBLWS\tFLWS\n'];
                    else
                        string_write = sprintf(['%11d\t%11d\t'...
                            '%9.9f\t%9.9f\t%9.9f\t%9.9f\t'...
                            '%9.9f\t%9.9f\t%9.9f\t%9.9f\n'], data.Header.Stamp.Sec, data.Header.Stamp.Nsec, temp_pack{:});
                    end
                    
                    
            elseif strcmp(name, 'magnetic_field')
                data = app.collected_data.magnetic_field{line};
                if line == 1
                string_write = ['Sec\tNsec\t'...
                    'MagX\tMagY\tMagZ\n'];
                else
                    string_write = sprintf(['%11d\t%11d\t'...
                        '%5.5f\t%5.5f\t%5.5f\n'], data.Header.Stamp.Sec, data.Header.Stamp.Nsec,...
                        data.MagneticField_.X,data.MagneticField_.Y,data.MagneticField_.Z);
                end
            end
            
            out_str = string_write;
            
        end
        
        function updateSimmetricAckermann(app)
            R_max = 5;
            R_min = 0.765569441437831;
            L = 0.42452915094;
            alphas = [-2.58308729295160,-0.558505360638,2.58308729295160,0.558505360638];
            
            wheels_speed = zeros(1,4);
            
            if app.left_key == 0
                %Go Straight
                R = Inf;
                theta_dot = 0;
                x_dot = app.up_key*app.delta_x;
                omega_w = app.up_key*app.delta_x/app.wheel_radius;
                %disp('doing-something')
                if omega_w > 200*0.229*2*pi/60
                    omega_w = 200*0.229*2*pi/60;
                    app.up_key = floor(omega_w*app.wheel_radius/app.delta_x);
                end
                
                if omega_w < -200*0.229*2*pi/60
                    omega_w = -200*0.229*2*pi/60;
                    app.up_key = floor(omega_w*app.wheel_radius/app.delta_x);
                end
                
                app.cmd_vel_msg.OnePrimary = omega_w;
                app.cmd_vel_msg.TwoPrimary = omega_w;
                app.cmd_vel_msg.ThreePrimary = omega_w;
                app.cmd_vel_msg.FourPrimary = omega_w;
                
            else
                if app.left_key > 0
                    R = R_max - app.left_key*app.delta_R;
                    if R < R_min
                        R = R_min;
                        app.left_key = floor((R_max - R_min)/app.delta_R);
                        
                    end
                elseif app.left_key < 0
                    R = -R_max - app.left_key*app.delta_R;
                    if R > -R_min
                        R=-R_min;
                        app.left_key = floor(-(R+R_max)/app.delta_R);
                        
                    end
                end
                
                
                    x_dot = app.up_key*app.delta_x;
                    theta_dot = x_dot/R;
                    
                    while true
                        wheels_speed(1) =  sign(app.up_key)*sqrt((theta_dot*(R-L*sin(alphas(1))))^2 + (theta_dot*L*cos(alphas(1)))^2)/app.wheel_radius;
                        wheels_speed(2) =  sign(app.up_key)*sqrt((theta_dot*(R-L*sin(alphas(2))))^2 + (theta_dot*L*cos(alphas(2)))^2)/app.wheel_radius;
                        wheels_speed(3) =  sign(app.up_key)*sqrt((theta_dot*(R-L*sin(alphas(3))))^2 + (theta_dot*L*cos(alphas(3)))^2)/app.wheel_radius;
                        wheels_speed(4) =  sign(app.up_key)*sqrt((theta_dot*(R-L*sin(alphas(4))))^2 + (theta_dot*L*cos(alphas(4)))^2)/app.wheel_radius;
                        
                        if all(abs(wheels_speed) <= 200*0.229*2*pi/60)
                            break
                        else
                            disp('entering here')
                            idy = find(abs(wheels_speed) > 200*0.229*2*pi/60);
                            omega_wheel = 200*0.229*2*pi/60;
                            theta_dot = sign(app.up_key)*sign(R)*omega_wheel*app.wheel_radius/(sqrt((R-L*sin(alphas(idy(1))))^2 + (L*cos(alphas(idy(1))))^2));
                            x_dot = R*theta_dot;
                            app.up_key = floor(x_dot/app.delta_x);
                            
                            break
                            
                        end
                        
                    end
                        
                        %Speed computation
                        app.cmd_vel_msg.OnePrimary = wheels_speed(1);
                        app.cmd_vel_msg.TwoPrimary = wheels_speed(2);
                        app.cmd_vel_msg.ThreePrimary = wheels_speed(3);
                        app.cmd_vel_msg.FourPrimary = wheels_speed(4);
                        
                        %Angular Position
                        app.cmd_vel_msg.FivePrimary = L*cos(alphas(1))/(R-L*sin(alphas(1)));
                        app.cmd_vel_msg.SixPrimary = L*cos(alphas(2))/(R-L*sin(alphas(2)));
                        app.cmd_vel_msg.SevenPrimary = L*cos(alphas(3))/(R-L*sin(alphas(3)));
                        app.cmd_vel_msg.EightPrimary = L*cos(alphas(4))/(R-L*sin(alphas(4)));
                
            end
            
            send(app.cmd_vel_pub,app.cmd_vel_msg);
            app.REditField.Value = R;
            app.x_dotEditField.Value = x_dot;
            app.theta_dotEditField.Value = theta_dot;
        end
        
        function updateParallelDrive(app)
            gamma_max = 0.58751447813558321651;
            max_wheel_speed = 200*0.229*2*pi/60;
            delta = app.left_key*0.017453292519943;
            if abs(delta) > gamma_max
                delta = sign(app.left_key)*gamma_max;
                app.left_key = floor(delta/0.017453292519943);
            end
            
            omega = app.up_key*app.delta_x/app.wheel_radius;
            if abs(omega) > max_wheel_speed
                omega = sign(app.up_key)*app.up_key;
                app.up_key = floor(max_wheel_speed*app.wheel_radius/app.delta_x);
            
            end
            
            app.cmd_vel_msg.OnePrimary = omega;
            app.cmd_vel_msg.TwoPrimary = omega;
            app.cmd_vel_msg.ThreePrimary = omega;
            app.cmd_vel_msg.FourPrimary = omega;
            
            app.cmd_vel_msg.FivePrimary = delta;
            app.cmd_vel_msg.SixPrimary = delta;
            app.cmd_vel_msg.SevenPrimary = delta;
            app.cmd_vel_msg.EightPrimary = delta;
            
            send(app.cmd_vel_pub,app.cmd_vel_msg)
        end
        
        function updateInplaceRotation(app)
            rotations = [0, 0, 0, 0, -1.01, 1.01, 1.01, -1.01];
            max_wheel_speed = 200*0.229*2*pi/60;
            %Right key clockwise rotation
            %Left key counter clockwise rotation
            omega = app.delta_x*app.left_key/app.wheel_radius;
            if abs(omega) > max_wheel_speed
                omega = sign(app.left_key)*max_wheel_speed;
                app.left_key = floor(omega*app.wheel_radius/app.delta_x);
            end
            app.cmd_vel_msg.OnePrimary = omega;
            app.cmd_vel_msg.TwoPrimary = omega;
            app.cmd_vel_msg.ThreePrimary = -omega;
            app.cmd_vel_msg.FourPrimary = -omega;
            app.cmd_vel_msg.FivePrimary = rotations(5);
            app.cmd_vel_msg.SixPrimary = rotations(6);
            app.cmd_vel_msg.SevenPrimary = rotations(7);
            app.cmd_vel_msg.EightPrimary = rotations(8);
            
            
            send(app.cmd_vel_pub, app.cmd_vel_msg)
        end
        
        function updateLateralDrive(app)
            rotations = [0, 0, 0, 0, -1.570796327, 1.570796327, 1.570796327, -1.570796327];
            max_wheel_speed = 200*0.229*2*pi/60;
            omega = app.delta_x*app.left_key/app.wheel_radius;
            if abs(omega) > max_wheel_speed
                omega = sign(app.left_key)*max_wheel_speed;
                app.left_key = floor(omega*app.wheel_radius/app.delta_x);
            end
            
            app.cmd_vel_msg.OnePrimary = -omega;
            app.cmd_vel_msg.TwoPrimary = omega;
            app.cmd_vel_msg.ThreePrimary = omega;
            app.cmd_vel_msg.FourPrimary = -omega;
            app.cmd_vel_msg.FivePrimary = rotations(5);
            app.cmd_vel_msg.SixPrimary = rotations(6);
            app.cmd_vel_msg.SevenPrimary = rotations(7);
            app.cmd_vel_msg.EightPrimary = rotations(8);
            
            
            send(app.cmd_vel_pub, app.cmd_vel_msg)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format long;
            app.UIFigure.Name = 'Rover Main Application';
            
            app.timer_var = timer();
            app.timer_var.TimerFcn = @(~,~)app.sample;
            app.timer_var.ExecutionMode = 'fixedRate';
            app.timer_var.Period = app.TsEditField.Value;
            app.timer_var.StartDelay = 0.3;
            
            
            app.timer_plot = timer();
            app.timer_plot.TimerFcn = @(~,~)app.updatePlots;
            app.timer_plot.ExecutionMode = 'fixedRate';
            app.timer_plot.Period = app.UpdatePeriodEditField.Value;
            app.timer_plot.StartDelay = 0.3;


            hold(app.UIAxes6,'on');
            xlim(app.UIAxes6,[-1,1])
            ylim(app.UIAxes6,[-1,1])
            app.reset();
            app.initialVisibility();
            app.checkCoreRunning();
            app.updateTopics();


        end

        % Callback function: EnableremotecontrolCheckBox, 
        % ModeSelectionButtonGroup
        function EnableremotecontrolCheckBoxValueChanged(app, event)
            value = app.EnableremotecontrolCheckBox.Value;
            if (value == false)
                app.ModeSelectionButtonGroup.Visible = 'off';
                
            elseif(value == true)
                app.ModeSelectionButtonGroup.Visible = 'on';
            end
            
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            app.TurningradiusSlider.Enable = false;
            app.MaxwheelsppedSlider.Enable = false;
            if app.EnableDisableRemotecontrolfromcontrollerCheckBox.Value == false
                rosparam("set","/rover_ws/controller_enable", int32(0));
            elseif app.EnableDisableRemotecontrolfromcontrollerCheckBox.Value == true
                rosparam("set","/rover_ws/controller_enable", int32(1));
                
                
            end
            
            rosparam("set","/rover_ws/max_wheel_speed",int32(app.MaxwheelspeedEditField.Value));
            rosparam("set","/rover_ws/max_turn_radius",app.MaxTurningRadiusEditField.Value);
            
            if app.EnableDisabletorqueCheckBox.Value == false
                pippo = app.motor_torque_msg;
                %disp(app.motor_torque_msg)
                pippo.Data = 0;
                send(app.motor_torque_pub, pippo);
            elseif app.EnableDisabletorqueCheckBox.Value == true
                app.motor_torque_msg.Data = 1;
                send(app.motor_torque_pub, app.motor_torque_msg);
                
            end
    
%             if app.EnableDisablewritetofiledatasCheckBox.Value == true
%                 %Open files
%                 %Get directory where to save
%                 if app.EditField.Value ~= ""
%                     app.odo_file = fopen(strcat(app.EditField.Value,'/Odometry_datas.txt'),'w');
%                     app.joint_file = fopen(strcat(app.EditField.Value,'/Joint_datas.txt'),'w');
%                     save(strcat(app.EditField.Value,'/Odometry.mat'));
%                     save(strcat(app.EditField.Value,'/JointStates.mat'));
%                 else
%                     app.odo_file = fopen('/home/ros/MalabData/Odometry_datas.txt','w');
%                     app.joint_file = fopen('/home/ros/MalabData/Joint_datas.txt','w');
%                     save('/home/ros/MalabData/Odometry.mat');
%                     save('/home/ros/MalabData/JointStates.mat');
%                 end
%                 
%             end
        end

        % Value changed function: 
        % EnableDisablewritetofiledatasCheckBox
        function EnableDisablewritetofiledatasCheckBoxValueChanged(app, event)
            value = app.EnableDisablewritetofiledatasCheckBox.Value;
            app.loop_check = true;
            if value == true
                app.SelectpathButton.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField.Value = strcat(pwd,'\data');
                %app.StartCollectingdatasButton.Visible = 'on';
                %app.StopButton.Visible = 'on';
            elseif value == false
                app.SelectpathButton.Visible = 'off';
                app.EditField.Visible = 'off';
                %app.StartCollectingdatasButton.Visible = 'off';
                %app.StopButton.Visible = 'off';
            end
        end

        % Button pushed function: SelectpathButton
        function SelectpathButtonPushed(app, event)
            path = uigetdir();
            app.EditField.Value = path;
        end

        % Button pushed function: StartCollectingdatasButton
        function StartCollectingdatasButtonPushed(app, event)
            %%Initialize a infinite while loop
            app.joint_state_window = 0;
            app.motor_temp_window = 0;
            app.motor_curr_window = 0;
            app.imu_window = 0;
            app.magnetic_field_window = 0;
            
            app.StartCollectingdatasButton.Enable = 'off';
            app.TsEditField.Enable = false;
            app.UpdatePeriodEditField.Enable = false;
            app.StopButton.Enable = 'on';
            app.ResetDataButton.Enable = false;
            
            app.udpateVisualizatioPanel(true); 


            app.timer_var.start();
            
            
            %%Declare second timer
            
            
            app.timer_plot.start();
            
            if app.EnableDisablewritetofiledatasCheckBox.Value == true
                app.data_saved = false;
            end
            
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
            app.timer_var.stop();
            
            
            %try
            app.timer_plot.stop();
            end
            app.TsEditField.Enable = true;
            app.UpdatePeriodEditField.Enable = true;
           
            app.loop_check = false;
            app.StartCollectingdatasButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.udpateVisualizatioPanel(false);
            app.ResetDataButton.Enable = true;
            
            if app.EnableDisablewritetofiledatasCheckBox.Value == true
                app.SaveButton_2.Enable = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = uiconfirm(app.UIFigure, 'Are you sure you want to exit? This will also terminate the ROS connection.','Exiting');
            try
            app.timer_var.delete()
            app.timer_plot.delete()
            end
            
            if strcmp(answer,'OK')
                rosshutdown
                delete(app)
                
            end

        end

        % Button pushed function: ChangeNetworkButton
        function ChangeNetworkButtonPushed(app, event)
            rosshutdown
            choose_network(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.updateTopics()
        end

        % Button pushed function: PingDeviceButton
        function PingDeviceButtonPushed(app, event)
            app_new = ping_test;
            waitfor(app_new)
        end

        % Button pushed function: InitializeCommButton
        function InitializeCommButtonPushed(app, event)
            app.initialize()
        end

        % Button pushed function: ChoosedatatoaquireButton
        function ChoosedatatoaquireButtonPushed(app, event)
            %Start data selection
            app.SelectedTopicsTextArea.Value = '';
            app.selected_topics_aquisition = [];
            new_app = data_aquisition_selection(app);
            waitfor(new_app)
            
            chosen = {};
            %Process values got from popup
            fields = fieldnames(app.selected_topics_aquisition);
            for i=1:numel(fields)
                tmp = fields{i};
                if app.selected_topics_aquisition.(tmp).selected == true
                    
                    chosen{end+1,1} = fields{i};
                    app.SelectedTopicsTextArea.Value{end+1} = fields{i};
                end
            end

            for i=1:numel(chosen)
                if i ==1
                    app.SelectedTopicsTextArea.Value = chosen{i};
                else
                    app.SelectedTopicsTextArea.Value = vertcat(app.SelectedTopicsTextArea.Value, chosen{i});
                end
            end
            
            
        end

        % Button pushed function: DiscoverButton
        function DiscoverButtonPushed(app, event)
            %Starting on discovery of IP Addresses of the network
            gateway = app.GatewayEditField.Value;
            app.UITable.Data = {};

            d = uiprogressdlg(app.UIFigure,"Title", "Searching for IP addresses on specified network", "Message","Initializing", "Cancelable","on");
            start = floor(app.StartEditField.Value);
            stop = floor(app.EndEditField.Value);
            delta = stop-start;
            num_to_check = linspace(start,stop,delta+1);
            base = split(gateway,'.');
            base_str = strcat(base{1},'.',base{2},'.', base{3},'.');
            for i=1:numel(num_to_check)
                d.Message = sprintf(strcat('Checking IP Address:\t',base_str,'%d'), num_to_check(i));
                d.Value = i/numel(num_to_check);
                if d.CancelRequested
                    break
                end
                
                new_str = strcat(base_str,num2str(num_to_check(i)));
                [a,~] = system(strcat("ping -n 1", " ", new_str));
                if a==0
                    %Success
                    app.UITable.Data{end+1,1} = new_str;
                end
                drawnow;
                
            end
            
            close(d)
            %app.UITable.UserData = IPS;
            
            
        end

        % Value changing function: GatewayEditField
        function GatewayEditFieldValueChanging(app, event)

            changingValue = event.Value;
            if isempty(regexp(changingValue,'^[0-9]+\.[0-9]+\.[0-9]+\.[0-9]+','match'))
                app.GatewayEditField.BackgroundColor = 'Red';
            else
                app.GatewayEditField.BackgroundColor = 'White';
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selected_ip = app.UITable.Data{indices(1), indices(2)};
        end

        % Button pushed function: ScriptloaderButton
        function ScriptloaderButtonPushed(app, event)
            [file, path, ~] = uigetfile('*.txt', 'Select the script to launch');
            if ~isequal(file,0)
                app.EditField_2.Value = strcat(path,file);
                app.SendandExecuteButton.Enable = true;
                app.EditScriptButton.Enable = true;
            end
            
        end

        % Button pushed function: SendandExecuteButton
        function SendandExecuteButtonPushed(app, event)
            cmd = strcat("putty"," ", "pi@", app.selected_ip," ",'-pw turtlebot -m'," ",...
                '"',app.EditField_2.Value,'"'," ", "&");
            system(cmd);
        end

        % Button pushed function: EditScriptButton
        function EditScriptButtonPushed(app, event)
            cmd = strcat("notepad", " ", '"',app.EditField_2.Value,'"'," ","&");
            system(cmd)
        end

        % Button pushed function: OpenSSHButton
        function OpenSSHButtonPushed(app, event)
            cmd = strcat("putty"," ", "pi@", app.selected_ip," ",'-pw turtlebot', " ","&");
            system(cmd)
        end

        % Button pushed function: AlarmButton
        function AlarmButtonPushed(app, event)
            if app.OffButton.Value == true
                app.alarm_msg.Value = 0;
            end
            if app.OnButton.Value == true
                app.alarm_msg.Value = 1;
            end
            if app.LowBatteryButton.Value == true
                app.alarm_msg.Value = 2;
            end
            if app.ErrorButton.Value == true
                app.alarm_msg.Value = 3;
            end
            if app.Button1.Value == true
                app.alarm_msg.Value = 4;
            end
            if app.Button2.Value == true
                app.alarm_msg.Value = 5;
            end
            send(app.alarm_pub, app.alarm_msg)
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            system('putty &')
        end

        % Button pushed function: OverrideButton
        function OverrideButtonPushed(app, event)
            answer = uiconfirm(app.UIFigure, 'Are you sure you want to override default parameters and pass to the manual selection?','Warning',"Icon",'warning');
            if strcmp(answer,'OK')
                app.MaxwheelsppedSlider.Enable = true;
                app.TurningradiusSlider.Enable = true;
            end
        end

        % Value changing function: TurningradiusSlider
        function TurningradiusSliderValueChanging(app, event)
            changingValue = event.Value;
            rosparam("set","/rover_ws/max_turn_radius",changingValue);
        end

        % Value changing function: MaxwheelsppedSlider
        function MaxwheelsppedSliderValueChanging(app, event)
            changingValue = event.Value;
            rosparam("set","/rover_ws/max_wheel_speed",int32(changingValue));
        end

        % Button pushed function: ShutdownDeviceButton
        function ShutdownDeviceButtonPushed(app, event)
            cmd = strcat("putty"," ", "pi@", app.selected_ip," ",'-pw turtlebot -m'," ",...
                '"','shutdown_device.txt','"'," ", "&");
            system(cmd)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile();
            app.PathtoVNCExecutableEditField.Value = strcat(file, path);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cmd = strcat('"',app.PathtoVNCExecutableEditField.Value,'"');
            %options = strcat(" ","-connect", " ",'pi@', app.selected_ip, " ", "-password turtlebot &");
            options = " &";
            [a,~] = system(strcat(cmd, options));
            if a == 1
                uialert(app.UIFigure, 'Couldnt locate a valid executable','Error')
            end
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            if app.data_saved == false
                answer = uiconfirm(app.UIFigure,'Data has not been saved. Do you want to proceed and discard it?', 'Warning','Options',{'OK','Save','Cancel'},'Icon','warning','DefaultOption',3);
                if strcmp(answer,'OK') 
                    app.reset()
                elseif strcmp(answer,'Save')
                    %save data
                
                    %reset
                end
                
            else
                app.reset()
            end


        end

        % Button pushed function: JointStatesButton
        function JointStatesButtonPushed(app, event)
            app.joint_state_window = joint_states_visualization(app);
            app.JointStatesButton.Enable = false;
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            if any(strcmp(app.topics_detected,'/raspicam_node/image/compressed'))...
                    && strcmp(class(app.camera_subscriber),'ros.Subscriber')
                
                %Get image from raspberry camera
                raw_compr = receive(app.camera_subscriber);
                raw_compr.Format = 'bgr8; jpeg compressed bgr8';
                
                im = readImage(raw_compr);
                
                app.CameraImage.ImageSource = im;
                app.ShowButton.Enable = true;
                app.SaveButton.Enable = true;
            end
        end

        % Button pushed function: MotorTemperatureButton
        function MotorTemperatureButtonPushed(app, event)
            app.motor_temp_window = temperature_visualization(app);
            app.MotorTemperatureButton.Enable = false;
        end

        % Value changed function: EnableTeleopCheckBox
        function EnableTeleopCheckBoxValueChanged(app, event)
            value = app.EnableTeleopCheckBox.Value;
            
            if value == true
                %Disable if active the remote controller support
                app.EnableDisableRemotecontrolfromcontrollerCheckBox.Value = false;
                app.EnableDisableRemotecontrolfromcontrollerCheckBox.Enable = false;
                
                app.EnableremotecontrolCheckBox.Value = false;
                app.EnableremotecontrolCheckBox.Enable = false;
            else
                app.EnableremotecontrolCheckBox.Enable = true;
                app.EnableDisableRemotecontrolfromcontrollerCheckBox.Enable = true;
                
                
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if app.EnableTeleopCheckBox.Value == true && ~app.emergency_teleop_triggered
                %Do things
                %disp(key);
                if strcmp(key,'uparrow')
                    app.up_key = app.up_key + 1;
                    
                elseif strcmp(key,'downarrow')
                    app.up_key = app.up_key -1;
                elseif strcmp(key,'leftarrow')
                    app.left_key = app.left_key + 1;
                elseif strcmp(key,'rightarrow')
                    app.left_key = app.left_key -1;
                end
                
                if app.AckermanSteerButton.Value == true
                    app.updateSimmetricAckermann();
                elseif app.ParallelDriveButton.Value == true
                    app.updateParallelDrive();
                elseif app.LateralDriveButton.Value == true
                    app.updateLateralDrive();
                elseif app.InPlaceRotationButton.Value == true
                    app.updateInplaceRotation();
                end
                
            end
        end

        % Button pushed function: ImuButton
        function ImuButtonPushed(app, event)
            app.ImuButton.Enable = false;
            app.imu_window = imu_visualization(app);
        end

        % Button pushed function: MotorCurrentButton
        function MotorCurrentButtonPushed(app, event)
            app.MotorCurrentButton.Enable = false;
            app.motor_curr_window = current_visualization(app);
        end

        % Button pushed function: MagneticFieldButton
        function MagneticFieldButtonPushed(app, event)
            app.MagneticFieldButton.Enable = false;
            app.magnetic_field_window = magnetic_field_visulization(app);
        end

        % Value changed function: TsEditField
        function TsEditFieldValueChanged(app, event)
            value = app.TsEditField.Value;
            app.timer_var.Period = value;
        end

        % Value changed function: UpdatePeriodEditField
        function UpdatePeriodEditFieldValueChanged(app, event)
            value = app.UpdatePeriodEditField.Value;
            app.timer_plot.Period = value;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            camera_viewer(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~exist(app.SaveLocationEditField.Value,'dir')
                mkdir(app.SaveLocationEditField.Value)
            end
            
            d = datetime('now');
            d.Format = 'yyyy_mm_dd__HH_MM_ss';
            file_name = strcat(app.SaveLocationEditField.Value,'\raspicamera_capture_',datestr(d,d.Format),'.jpg');
            imwrite(app.CameraImage.ImageSource,file_name)


        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            path = uigetdir();
            
            if isstr(path)
                app.SaveLocationEditField.Value = path;
            end
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.saveData()
        end

        % Selection changed function: OperatingModeButtonGroup
        function OperatingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperatingModeButtonGroup.SelectedObject;
            app.up_key = 0;
            app.left_key = 0;
            
            
            app.cmd_vel_msg.OnePrimary = 0;
            app.cmd_vel_msg.TwoPrimary = 0;
            app.cmd_vel_msg.ThreePrimary = 0;
            app.cmd_vel_msg.FourPrimary = 0;
            app.cmd_vel_msg.FivePrimary = 0;
            app.cmd_vel_msg.SixPrimary = 0;
            app.cmd_vel_msg.SevenPrimary = 0;
            app.cmd_vel_msg.EightPrimary = 0;
            
            
            send(app.cmd_vel_pub, app.cmd_vel_msg)
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.emergency_teleop_triggered = true;
            
            
            app.cmd_vel_msg.OnePrimary = 0;
            app.cmd_vel_msg.TwoPrimary = 0;
            app.cmd_vel_msg.ThreePrimary = 0;
            app.cmd_vel_msg.FourPrimary = 0;
            app.cmd_vel_msg.FivePrimary = 0;
            app.cmd_vel_msg.SixPrimary = 0;
            app.cmd_vel_msg.SevenPrimary = 0;
            app.cmd_vel_msg.EightPrimary = 0;
            
            
            send(app.cmd_vel_pub, app.cmd_vel_msg)
        end

        % Button pushed function: EmergencyUnlockButton
        function EmergencyUnlockButtonPushed(app, event)
            app.emergency_teleop_triggered = false;
        end

        % Value changed function: MaxwheelspeedEditField
        function MaxwheelspeedEditFieldValueChanged(app, event)
            value = app.MaxwheelspeedEditField.Value;
            app.maxRPMEditField.Value = num2str(value*0.229);
            app.maxXSpeedEditField.Value = num2str(value*0.2292*2*pi/60*app.wheel_radius);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.IpFinderPanel = uipanel(app.GeneralTab);
            app.IpFinderPanel.Title = 'Ip Finder';
            app.UITable = uitable(app.IpFinderPanel);
            app.UITable.ColumnName = {'IP'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StartEditFieldLabel = uilabel(app.IpFinderPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.IpFinderPanel, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.Value = 100;
            app.EndEditFieldLabel = uilabel(app.IpFinderPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.IpFinderPanel, 'numeric');
            app.EndEditField.Limits = [1 Inf];
            app.EndEditField.ValueDisplayFormat = '%.0f';
            app.EndEditField.Value = 150;
            app.DiscoverButton = uibutton(app.IpFinderPanel, 'push');
            app.DiscoverButton.ButtonPushedFcn = createCallbackFcn(app, @DiscoverButtonPushed, true);
            app.DiscoverButton.Text = 'Discover';
            app.GatewayEditFieldLabel = uilabel(app.IpFinderPanel);
            app.GatewayEditFieldLabel.Text = 'Gateway';
            app.GatewayEditField = uieditfield(app.IpFinderPanel, 'text');
            app.GatewayEditField.ValueChangingFcn = createCallbackFcn(app, @GatewayEditFieldValueChanging, true);
            app.GatewayEditField.Value = '192.168.1.1';
            app.ROSGeneralInformationsPanel = uipanel(app.GeneralTab);
            app.ROSGeneralInformationsPanel.Title = 'ROS General Informations';
            app.TopicsdetectedTextAreaLabel = uilabel(app.ROSGeneralInformationsPanel);
            app.TopicsdetectedTextAreaLabel.Text = 'Topics detected:';
            app.TopicsdetectedTextArea = uitextarea(app.ROSGeneralInformationsPanel);
            app.TopicsdetectedTextArea.Editable = 'off';
            app.TopicsdetectedTextArea.Tooltip = {'List of the topics that have been detected'};
            app.NodesdetectedTextAreaLabel = uilabel(app.ROSGeneralInformationsPanel);
            app.NodesdetectedTextAreaLabel.Text = 'Nodes detected';
            app.NodesdetectedTextArea = uitextarea(app.ROSGeneralInformationsPanel);
            app.NodesdetectedTextArea.Editable = 'off';
            app.UpdateButton = uibutton(app.ROSGeneralInformationsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.InitializeCommButton = uibutton(app.ROSGeneralInformationsPanel, 'push');
            app.InitializeCommButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeCommButtonPushed, true);
            app.InitializeCommButton.Enable = 'off';
            app.InitializeCommButton.Text = 'Initialize Comm';
            app.NetworkandRemoteRoverSetupPanel = uipanel(app.GeneralTab);
            app.NetworkandRemoteRoverSetupPanel.Title = 'Network and Remote Rover Setup';
            app.ChangeNetworkButton = uibutton(app.NetworkandRemoteRoverSetupPanel, 'push');
            app.ChangeNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeNetworkButtonPushed, true);
            app.ChangeNetworkButton.Text = 'Change Network';
            app.PingDeviceButton = uibutton(app.NetworkandRemoteRoverSetupPanel, 'push');
            app.PingDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @PingDeviceButtonPushed, true);
            app.PingDeviceButton.Text = 'Ping Device';
            app.RemoteStartUpPanel = uipanel(app.NetworkandRemoteRoverSetupPanel);
            app.RemoteStartUpPanel.Title = 'Remote Start-Up';
            app.ScriptloaderButton = uibutton(app.RemoteStartUpPanel, 'push');
            app.ScriptloaderButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptloaderButtonPushed, true);
            app.ScriptloaderButton.Text = 'Script loader';
            app.EditField_2 = uieditfield(app.RemoteStartUpPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.SendandExecuteButton = uibutton(app.RemoteStartUpPanel, 'push');
            app.SendandExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @SendandExecuteButtonPushed, true);
            app.SendandExecuteButton.Text = 'Send and Execute';
            app.EditScriptButton = uibutton(app.RemoteStartUpPanel, 'push');
            app.EditScriptButton.ButtonPushedFcn = createCallbackFcn(app, @EditScriptButtonPushed, true);
            app.EditScriptButton.Text = 'Edit Script';
            app.SSHPanel = uipanel(app.RemoteStartUpPanel);
            app.SSHPanel.Title = 'SSH';
            app.OpenSSHButton = uibutton(app.SSHPanel, 'push');
            app.OpenSSHButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSSHButtonPushed, true);
            app.OpenSSHButton.Text = 'Open SSH';
            app.SettingsButton = uibutton(app.SSHPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.ShutdownDeviceButton = uibutton(app.NetworkandRemoteRoverSetupPanel, 'push');
            app.ShutdownDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownDeviceButtonPushed, true);
            app.ShutdownDeviceButton.Text = 'Shutdown Device';
            app.ConnectionstatusLampLabel = uilabel(app.NetworkandRemoteRoverSetupPanel);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.NetworkandRemoteRoverSetupPanel);
            app.RemoteDesktopPanel = uipanel(app.NetworkandRemoteRoverSetupPanel);
            app.RemoteDesktopPanel.Title = 'Remote Desktop';
            app.BrowseButton = uibutton(app.RemoteDesktopPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PathtoVNCExecutableLabel = uilabel(app.RemoteDesktopPanel);
            app.PathtoVNCExecutableLabel.Text = {'Path to VNC'; 'Executable'};
            app.PathtoVNCExecutableEditField = uieditfield(app.RemoteDesktopPanel, 'text');
            app.PathtoVNCExecutableEditField.Value = 'C:\Program Files\RealVNC\VNC Viewer\vncviewer.exe';
            app.StartButton = uibutton(app.RemoteDesktopPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ApplyButton_2 = uibutton(app.SettingsTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.AquisitionPanel = uipanel(app.SettingsTab);
            app.AquisitionPanel.Title = 'Aquisition';
            app.StartCollectingdatasButton = uibutton(app.AquisitionPanel, 'push');
            app.StartCollectingdatasButton.ButtonPushedFcn = createCallbackFcn(app, @StartCollectingdatasButtonPushed, true);
            app.StartCollectingdatasButton.Text = 'Start Collecting datas';
            app.StopButton = uibutton(app.AquisitionPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SelectedTopicsTextAreaLabel = uilabel(app.AquisitionPanel);
            app.SelectedTopicsTextAreaLabel.Text = 'Selected Topics';
            app.SelectedTopicsTextArea = uitextarea(app.AquisitionPanel);
            app.SelectedTopicsTextArea.Editable = 'off';
            app.ChoosedatatoaquireButton = uibutton(app.AquisitionPanel, 'push');
            app.ChoosedatatoaquireButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosedatatoaquireButtonPushed, true);
            app.ChoosedatatoaquireButton.Text = 'Choose data to aquire';
            app.TsEditFieldLabel = uilabel(app.AquisitionPanel);
            app.TsEditFieldLabel.Text = 'Ts';
            app.TsEditField = uieditfield(app.AquisitionPanel, 'numeric');
            app.TsEditField.Limits = [0 Inf];
            app.TsEditField.ValueChangedFcn = createCallbackFcn(app, @TsEditFieldValueChanged, true);
            app.TsEditField.Value = 0.1;
            app.ResetDataButton = uibutton(app.AquisitionPanel, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.SaveButton_2 = uibutton(app.AquisitionPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.OverrideDefaultParametersPanel = uipanel(app.SettingsTab);
            app.OverrideDefaultParametersPanel.Title = 'Override Default Parameters';
            app.OverrideButton = uibutton(app.OverrideDefaultParametersPanel, 'push');
            app.OverrideButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideButtonPushed, true);
            app.OverrideButton.Text = 'Override';
            app.TurningradiusSliderLabel = uilabel(app.OverrideDefaultParametersPanel);
            app.TurningradiusSliderLabel.Text = 'Turning radius';
            app.TurningradiusSlider = uislider(app.OverrideDefaultParametersPanel);
            app.TurningradiusSlider.Limits = [0 3];
            app.TurningradiusSlider.ValueChangingFcn = createCallbackFcn(app, @TurningradiusSliderValueChanging, true);
            app.TurningradiusSlider.Value = 3;
            app.MaxwheelsppedSliderLabel = uilabel(app.OverrideDefaultParametersPanel);
            app.MaxwheelsppedSliderLabel.Text = 'Max wheel spped';
            app.MaxwheelsppedSlider = uislider(app.OverrideDefaultParametersPanel);
            app.MaxwheelsppedSlider.Limits = [0 200];
            app.MaxwheelsppedSlider.ValueChangingFcn = createCallbackFcn(app, @MaxwheelsppedSliderValueChanging, true);
            app.MaxwheelsppedSlider.Value = 200;
            app.GeneralOptionsPanel = uipanel(app.SettingsTab);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.EnableDisableRemotecontrolfromcontrollerCheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.EnableDisableRemotecontrolfromcontrollerCheckBox.Text = 'Enable/Disable Remote control from controller';
            app.EnableDisabletorqueCheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.EnableDisabletorqueCheckBox.Text = 'Enable/Disable torque';
            app.EnableDisablewritetofiledatasCheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.EnableDisablewritetofiledatasCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableDisablewritetofiledatasCheckBoxValueChanged, true);
            app.EnableDisablewritetofiledatasCheckBox.Text = 'Enable/Disable write to file datas';
            app.SelectpathButton = uibutton(app.GeneralOptionsPanel, 'push');
            app.SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButtonPushed, true);
            app.SelectpathButton.Text = 'Select path';
            app.EditField = uieditfield(app.GeneralOptionsPanel, 'text');
            app.DriveOptionsPanel = uipanel(app.SettingsTab);
            app.DriveOptionsPanel.Title = 'Drive Options';
            app.MaxwheelspeedEditFieldLabel = uilabel(app.DriveOptionsPanel);
            app.MaxwheelspeedEditFieldLabel.Text = 'Max wheel speed';
            app.MaxwheelspeedEditField = uieditfield(app.DriveOptionsPanel, 'numeric');
            app.MaxwheelspeedEditField.Limits = [0 1000];
            app.MaxwheelspeedEditField.ValueDisplayFormat = '%.0f';
            app.MaxwheelspeedEditField.ValueChangedFcn = createCallbackFcn(app, @MaxwheelspeedEditFieldValueChanged, true);
            app.MaxwheelspeedEditField.Tooltip = {'Maximum speed of the wheels expressed in rpm'};
            app.MaxwheelspeedEditField.Value = 200;
            app.MaxTurningRadiusEditFieldLabel = uilabel(app.DriveOptionsPanel);
            app.MaxTurningRadiusEditFieldLabel.Text = 'Max Turning Radius';
            app.MaxTurningRadiusEditField = uieditfield(app.DriveOptionsPanel, 'numeric');
            app.MaxTurningRadiusEditField.Limits = [1 Inf];
            app.MaxTurningRadiusEditField.ValueDisplayFormat = '%5.2f';
            app.MaxTurningRadiusEditField.Value = 1.7;
            app.maxRPMEditFieldLabel = uilabel(app.DriveOptionsPanel);
            app.maxRPMEditFieldLabel.Text = 'maxRPM';
            app.maxRPMEditField = uieditfield(app.DriveOptionsPanel, 'text');
            app.maxRPMEditField.Editable = 'off';
            app.maxXSpeedEditFieldLabel = uilabel(app.DriveOptionsPanel);
            app.maxXSpeedEditFieldLabel.Text = 'maxXSpeed';
            app.maxXSpeedEditField = uieditfield(app.DriveOptionsPanel, 'text');
            app.maxXSpeedEditField.Editable = 'off';
            app.RemoteControlTab = uitab(app.TabGroup);
            app.RemoteControlTab.Title = 'Remote Control';
            app.EnableremotecontrolCheckBox = uicheckbox(app.RemoteControlTab);
            app.EnableremotecontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableremotecontrolCheckBoxValueChanged, true);
            app.EnableremotecontrolCheckBox.Text = 'Enable remote control';
            app.ModeSelectionButtonGroup = uibuttongroup(app.RemoteControlTab);
            app.ModeSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnableremotecontrolCheckBoxValueChanged, true);
            app.ModeSelectionButtonGroup.Title = 'Mode Selection';
            app.ModeSelectionButtonGroup.Visible = 'off';
            app.DriveMode1Button = uiradiobutton(app.ModeSelectionButtonGroup);
            app.DriveMode1Button.Text = 'Drive Mode 1';
            app.DriveMode1Button.Value = true;
            app.DriveMode2Button = uiradiobutton(app.ModeSelectionButtonGroup);
            app.DriveMode2Button.Text = 'Drive Mode 2';
            app.DriveMode3Button = uiradiobutton(app.ModeSelectionButtonGroup);
            app.DriveMode3Button.Text = 'Drive Mode 3';
            app.DriveMode4Button = uiradiobutton(app.ModeSelectionButtonGroup);
            app.DriveMode4Button.Text = 'Drive Mode 4';
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.UIAxes6 = uiaxes(app.SummaryTab);
            title(app.UIAxes6, 'Rover''s Position')
            xlabel(app.UIAxes6, 'X [m]')
            ylabel(app.UIAxes6, 'Y [m]')
            app.HeadingIndicatorLabel = uilabel(app.SummaryTab);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.SummaryTab);
            app.PositionPanel = uipanel(app.SummaryTab);
            app.PositionPanel.Title = 'Position';
            app.XEditFieldLabel = uilabel(app.PositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.PositionPanel, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.PositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PositionPanel, 'numeric');
            app.YEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.PositionPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.PositionPanel, 'numeric');
            app.YawEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.PositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PositionPanel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.PositionPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.PositionPanel, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.PositionPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.PositionPanel, 'numeric');
            app.RollEditField.Editable = 'off';
            app.SpeedPanel = uipanel(app.SummaryTab);
            app.SpeedPanel.Title = 'Speed';
            app.XEditField_2Label = uilabel(app.SpeedPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.SpeedPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.YawEditField_2Label = uilabel(app.SpeedPanel);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.YawEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.SpeedPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.ZEditField_2.Editable = 'off';
            app.PitchEditField_2Label = uilabel(app.SpeedPanel);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.PitchEditField_2.Editable = 'off';
            app.RollEditField_2Label = uilabel(app.SpeedPanel);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.SpeedPanel, 'numeric');
            app.RollEditField_2.Editable = 'off';
            app.AlarmPanel = uipanel(app.SummaryTab);
            app.AlarmPanel.Title = 'Alarm';
            app.AlarmOptionsButtonGroup = uibuttongroup(app.AlarmPanel);
            app.AlarmOptionsButtonGroup.Title = 'Alarm Options';
            app.OffButton = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.OnButton.Text = 'On';
            app.LowBatteryButton = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.LowBatteryButton.Text = 'Low Battery';
            app.ErrorButton = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.ErrorButton.Text = 'Error';
            app.Button1 = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.Button1.Text = 'Button 1';
            app.Button2 = uiradiobutton(app.AlarmOptionsButtonGroup);
            app.Button2.Text = 'Button 2';
            app.AlarmButton = uibutton(app.AlarmPanel, 'push');
            app.AlarmButton.ButtonPushedFcn = createCallbackFcn(app, @AlarmButtonPushed, true);
            app.AlarmButton.Text = 'Alarm';
            app.OdometricYawEditFieldLabel = uilabel(app.SummaryTab);
            app.OdometricYawEditFieldLabel.Text = 'Odometric Yaw';
            app.OdometricYawEditField = uieditfield(app.SummaryTab, 'numeric');
            app.OdometricYawSpeedEditFieldLabel = uilabel(app.SummaryTab);
            app.OdometricYawSpeedEditFieldLabel.Text = 'Odometric Yaw Speed';
            app.OdometricYawSpeedEditField = uieditfield(app.SummaryTab, 'numeric');
            app.VisualizingTab = uitab(app.TabGroup);
            app.VisualizingTab.Title = 'Visualizing';
            app.WindowSelectionPanel = uipanel(app.VisualizingTab);
            app.WindowSelectionPanel.Title = 'Window Selection';
            app.JointStatesButton = uibutton(app.WindowSelectionPanel, 'push');
            app.JointStatesButton.ButtonPushedFcn = createCallbackFcn(app, @JointStatesButtonPushed, true);
            app.JointStatesButton.Text = 'Joint States';
            app.UpdatePeriodEditFieldLabel = uilabel(app.WindowSelectionPanel);
            app.UpdatePeriodEditFieldLabel.Text = 'Update Period';
            app.UpdatePeriodEditField = uieditfield(app.WindowSelectionPanel, 'numeric');
            app.UpdatePeriodEditField.ValueChangedFcn = createCallbackFcn(app, @UpdatePeriodEditFieldValueChanged, true);
            app.UpdatePeriodEditField.Value = 0.5;
            app.ImuButton = uibutton(app.WindowSelectionPanel, 'push');
            app.ImuButton.ButtonPushedFcn = createCallbackFcn(app, @ImuButtonPushed, true);
            app.ImuButton.Text = 'Imu';
            app.MotorTemperatureButton = uibutton(app.WindowSelectionPanel, 'push');
            app.MotorTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @MotorTemperatureButtonPushed, true);
            app.MotorTemperatureButton.Text = 'Motor Temperature';
            app.MotorCurrentButton = uibutton(app.WindowSelectionPanel, 'push');
            app.MotorCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @MotorCurrentButtonPushed, true);
            app.MotorCurrentButton.Text = 'Motor Current';
            app.MagneticFieldButton = uibutton(app.WindowSelectionPanel, 'push');
            app.MagneticFieldButton.ButtonPushedFcn = createCallbackFcn(app, @MagneticFieldButtonPushed, true);
            app.MagneticFieldButton.Text = 'Magnetic Field';
            app.CameraPanel = uipanel(app.VisualizingTab);
            app.CameraPanel.Title = 'Camera';
            app.CaptureButton = uibutton(app.CameraPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.BrowseButton_2 = uibutton(app.CameraPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SaveLocationLabel = uilabel(app.CameraPanel);
            app.SaveLocationLabel.Text = {'Save '; 'Location'};
            app.SaveLocationEditField = uieditfield(app.CameraPanel, 'text');
            app.SaveButton = uibutton(app.CameraPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CameraImage = uiimage(app.CameraPanel);
            app.ShowButton = uibutton(app.CameraPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.UIAxes7 = uiaxes(app.VisualizingTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes7.XTick = [0 0.5 1];
            app.UIAxes7.XTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes7.ZTickLabel = {'z'};
            app.TeleopTab = uitab(app.TabGroup);
            app.TeleopTab.Title = 'Teleop';
            app.EnableTeleopCheckBox = uicheckbox(app.TeleopTab);
            app.EnableTeleopCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableTeleopCheckBoxValueChanged, true);
            app.EnableTeleopCheckBox.Text = 'Enable Teleop';
            app.TextArea = uitextarea(app.TeleopTab);
            app.TextArea.Editable = 'off';
            app.InstructionsLabel = uilabel(app.TeleopTab);
            app.InstructionsLabel.Text = 'Instructions';
            app.OperatingModeButtonGroup = uibuttongroup(app.TeleopTab);
            app.OperatingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperatingModeButtonGroupSelectionChanged, true);
            app.OperatingModeButtonGroup.Title = 'Operating Mode';
            app.AckermanSteerButton = uiradiobutton(app.OperatingModeButtonGroup);
            app.AckermanSteerButton.Text = 'Ackerman Steer';
            app.AckermanSteerButton.Value = true;
            app.ParallelDriveButton = uiradiobutton(app.OperatingModeButtonGroup);
            app.ParallelDriveButton.Text = 'Parallel Drive';
            app.LateralDriveButton = uiradiobutton(app.OperatingModeButtonGroup);
            app.LateralDriveButton.Text = 'Lateral Drive';
            app.InPlaceRotationButton = uiradiobutton(app.OperatingModeButtonGroup);
            app.InPlaceRotationButton.Text = 'In-Place Rotation';
            app.CommandedPanel = uipanel(app.TeleopTab);
            app.CommandedPanel.Title = 'Commanded';
            app.AckermannPanel = uipanel(app.CommandedPanel);
            app.AckermannPanel.Title = 'Ackermann';
            app.REditFieldLabel = uilabel(app.AckermannPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.AckermannPanel, 'numeric');
            app.x_dotEditFieldLabel = uilabel(app.AckermannPanel);
            app.x_dotEditFieldLabel.Text = 'x_dot';
            app.x_dotEditField = uieditfield(app.AckermannPanel, 'numeric');
            app.theta_dotEditFieldLabel = uilabel(app.AckermannPanel);
            app.theta_dotEditFieldLabel.Text = 'theta_dot';
            app.theta_dotEditField = uieditfield(app.AckermannPanel, 'numeric');
            app.EmergencyStopButton = uibutton(app.TeleopTab, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.EmergencyUnlockButton = uibutton(app.TeleopTab, 'push');
            app.EmergencyUnlockButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyUnlockButtonPushed, true);
            app.EmergencyUnlockButton.Text = 'Emergency Unlock';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end