classdef odometry_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_app; % Description
        is_fake = false;
        initialized = false;
    end
    
    methods (Access = public)
        
        function flag = isInitialized(app)
            flag = app.initialized;

        end
        
        function updatePlots(app)
            data = app.main_app.collected_data.odometry{end};
            plot(app.UIAxes,data.pose.pose.position.x, data.pose.pose.position.y,'-b')
        end
        
    end
    
    methods (Access = private)
        
        function initializePlots(app)
            hold(app.UIAxes,'on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            app.initializePlots()
            app.initialized = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.is_fake == true
                app.timer_data.stop()
                app.timer_data.delete()
            end
            
            app.main_app.odometry_window = 0;
            app.main_app.OdomemetryButton.Enable = true;
            
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rover Pose')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odometry_visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end