classdef ping_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloseButton                    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        IPAddresstoPingEditFieldLabel  matlab.ui.control.Label
        IPAddresstoPingEditField       matlab.ui.control.EditField
        PingButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Ping Tester';
        end

        % Value changing function: IPAddresstoPingEditField
        function IPAddresstoPingEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if isempty(regexp(changingValue,'^[0-9]+\.[0-9]+\.[0-9]+\.[0-9]+','match'))
                app.IPAddresstoPingEditField.BackgroundColor = 'Red';
            else
                app.IPAddresstoPingEditField.BackgroundColor = 'White';
                app.PingButton.Enable = true;
            end
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            app.TextArea.Value = '';
            drawnow;
            [~, output] = system(strcat('ping'," ", app.IPAddresstoPingEditField.Value));
            app.TextArea.Value = output;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.IPAddresstoPingEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddresstoPingEditFieldLabel.Text = 'IP Address to Ping';
            app.IPAddresstoPingEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddresstoPingEditField.ValueChangingFcn = createCallbackFcn(app, @IPAddresstoPingEditFieldValueChanging, true);
            app.IPAddresstoPingEditField.Value = '0.0.0.0';
            app.PingButton = uibutton(app.UIFigure, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Enable = 'off';
            app.PingButton.Text = 'Ping';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ping_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end