classdef temperature_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        GaugeIndicatorsPanel            matlab.ui.container.Panel
        BackRightDriveMotorGaugeLabel   matlab.ui.control.Label
        BackRightDriveMotorGauge        matlab.ui.control.NinetyDegreeGauge
        FrontRightDriveMotorGaugeLabel  matlab.ui.control.Label
        FrontRightDriveMotorGauge       matlab.ui.control.NinetyDegreeGauge
        BackLeftDriveMotorGaugeLabel    matlab.ui.control.Label
        BackLeftDriveMotorGauge         matlab.ui.control.NinetyDegreeGauge
        FrontLeftDriveMotorGaugeLabel   matlab.ui.control.Label
        FrontLeftDriveMotorGauge        matlab.ui.control.NinetyDegreeGauge
        BackRightSteerMotorGaugeLabel   matlab.ui.control.Label
        BackRightSteerMotorGauge        matlab.ui.control.NinetyDegreeGauge
        FrontRightSteerMotorGaugeLabel  matlab.ui.control.Label
        FrontRightSteerMotorGauge       matlab.ui.control.NinetyDegreeGauge
        BackLeftSteerMotorGaugeLabel    matlab.ui.control.Label
        BackLeftSteerMotorGauge         matlab.ui.control.NinetyDegreeGauge
        FrontLeftSteerMotorGaugeLabel   matlab.ui.control.Label
        FrontLeftSteerMotorGauge        matlab.ui.control.NinetyDegreeGauge
        OptionsPanel                    matlab.ui.container.Panel
        EnableIndicatorsCheckBox        matlab.ui.control.CheckBox
        ShowGraphsCheckBox              matlab.ui.control.CheckBox
        RawDataPanel                    matlab.ui.container.Panel
        TemperaturePanel                matlab.ui.container.Panel
        T1                              matlab.ui.control.NumericEditField
        T2                              matlab.ui.control.NumericEditField
        T3                              matlab.ui.control.NumericEditField
        T4                              matlab.ui.control.NumericEditField
        T5                              matlab.ui.control.NumericEditField
        T6                              matlab.ui.control.NumericEditField
        T7                              matlab.ui.control.NumericEditField
        T8                              matlab.ui.control.NumericEditField
        BRDMLabel                       matlab.ui.control.Label
        FRDMLabel                       matlab.ui.control.Label
        BLDMLabel                       matlab.ui.control.Label
        FLDMLabel                       matlab.ui.control.Label
        BRSMLabel                       matlab.ui.control.Label
        FRSMLabel                       matlab.ui.control.Label
        BLSMLabel                       matlab.ui.control.Label
        FLSMLabel                       matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        main_app; % Description
    end
    
    properties (Access = private)
        line_t1;
        line_t2; % Description
        line_t3;
        line_t4;
        line_t5;
        line_t6;
        line_t7;
        line_t8;
        
        initial_time_plot;
        legend_temperature;
        
        plot_initialized = false;
        plot_resetted = true;
        last_data;
        initialized = false;
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            data = app.main_app.collected_data.motor_temperature{end};
            app.last_data = data;
            data = app.convertToDouble(data);
            app.T1.Value = data.BRWFDynamixelTemperature;
            app.T2.Value = data.FRWFDynamixelTemperature;
            app.T3.Value = data.BLWFDynamixelTemperature;
            app.T4.Value = data.FLWFDynamixelTemperature;
            app.T5.Value = data.BRWSDynamixelTemperature;
            app.T6.Value = data.FRWSDynamixelTemperature;
            app.T7.Value = data.BLWSDynamixelTemperature;
            app.T8.Value = data.FLWSDynamixelTemperature;


            if app.EnableIndicatorsCheckBox.Value == true
                app.BackRightDriveMotorGauge.Value = data.BRWFDynamixelTemperature;
                app.FrontRightDriveMotorGauge.Value = data.FRWFDynamixelTemperature;
                app.BackLeftDriveMotorGauge.Value = data.BLWFDynamixelTemperature;
                app.FrontLeftDriveMotorGauge.Value = data.FLWFDynamixelTemperature;
                
                app.BackRightSteerMotorGauge.Value = data.BRWSDynamixelTemperature;
                app.FrontRightSteerMotorGauge.Value = data.FRWSDynamixelTemperature;
                app.BackLeftSteerMotorGauge.Value = data.BLWSDynamixelTemperature;
                app.FrontLeftSteerMotorGauge.Value = data.FLWSDynamixelTemperature;


            end
            
            %Update Graphs
            if app.ShowGraphsCheckBox.Value == true
                time = data.Header.Stamp - app.initial_time_plot;
                time1 = time.Sec + time.Nsec/1e9;
                app.updateTemperaturePlot(data, time1)
                if app.plot_resetted
                    app.plot_resetted = false;
                end
                
            end
            
            
        end
        
        function flag = isInitialized(app)
            flag = app.initialized;
            
        end
    end
    
    methods (Access = private)
        
        function initializePlot(app)
            hold(app.UIAxes,'on')
            app.line_t1 = plot(app.UIAxes,0,0,'-r');
            app.line_t2 = plot(app.UIAxes,0,0,'-b');
            app.line_t3 = plot(app.UIAxes,0,0,'-g');
            app.line_t4 = plot(app.UIAxes,0,0,'-k');
            app.line_t5 = plot(app.UIAxes,0,0,'-.r');
            app.line_t6 = plot(app.UIAxes,0,0,'-.b');
            app.line_t7 = plot(app.UIAxes,0,0,'-.g');
            app.line_t8 = plot(app.UIAxes,0,0,'-.k');
            app.legend_temperature = legend(app.UIAxes,...
                {'BRDM','FRDM','BLDM','FLDM','BRSM','FRSM','BLSM', 'FLSM'},"AutoUpdate","off");
            
            app.plot_initialized = true;
            
            
        end
        
        function updateTemperaturePlot(app, msg, time)
            if app.plot_resetted
                app.line_t1.XData = time;
                app.line_t1.YData = msg.BRWFDynamixelTemperature;
                
                app.line_t2.XData = time;
                app.line_t2.YData = msg.FRWFDynamixelTemperature;
                
                app.line_t3.XData = time;
                app.line_t3.YData = msg.BLWFDynamixelTemperature;
                
                app.line_t4.XData = time;
                app.line_t4.YData = msg.FLWFDynamixelTemperature;
                
                app.line_t5.XData = time;
                app.line_t5.YData = msg.BRWSDynamixelTemperature;
                
                app.line_t6.XData = time;
                app.line_t6.YData = msg.FRWSDynamixelTemperature;
                
                app.line_t7.XData = time;
                app.line_t7.YData = msg.BLWSDynamixelTemperature;
                
                app.line_t8.XData = time;
                app.line_t8.YData = msg.FLWSDynamixelTemperature;
                
            else
                app.line_t1.XData(end+1) = time;
                app.line_t1.YData(end+1) = msg.BRWFDynamixelTemperature;
                
                app.line_t2.XData(end+1) = time;
                app.line_t2.YData(end+1) = msg.FRWFDynamixelTemperature;
                
                app.line_t3.XData(end+1) = time;
                app.line_t3.YData(end+1) = msg.BLWFDynamixelTemperature;
                
                app.line_t4.XData(end+1) = time;
                app.line_t4.YData(end+1) = msg.FLWFDynamixelTemperature;
                
                app.line_t5.XData(end+1) = time;
                app.line_t5.YData(end+1) = msg.BRWSDynamixelTemperature;
                
                app.line_t6.XData(end+1) = time;
                app.line_t6.YData(end+1) = msg.FRWSDynamixelTemperature;
                
                app.line_t7.XData(end+1) = time;
                app.line_t7.YData(end+1) = msg.BLWSDynamixelTemperature;
                
                app.line_t8.XData(end+1) = time;
                app.line_t8.YData(end+1) = msg.FLWSDynamixelTemperature;
            end
            
        end
        
        function resetPlot(app)
                app.line_t1.XData = app.line_t1.XData(1);
                app.line_t1.YData = app.line_t1.YData(1);
                
                app.line_t2.XData = app.line_t2.XData(1);
                app.line_t2.YData = app.line_t2.YData(1);
                
                app.line_t3.XData = app.line_t3.XData(1);
                app.line_t3.YData = app.line_t3.YData(1);
                
                app.line_t4.XData = app.line_t4.XData(1);
                app.line_t4.YData = app.line_t4.YData(1);
                
                app.line_t5.XData = app.line_t5.XData(1);
                app.line_t5.YData = app.line_t5.YData(1);
                
                app.line_t6.XData = app.line_t6.XData(1);
                app.line_t6.YData = app.line_t6.YData(1);
                
                app.line_t7.XData = app.line_t7.XData(1);
                app.line_t7.YData = app.line_t7.YData(1);
                
                app.line_t8.XData = app.line_t8.XData(1);
                app.line_t8.YData = app.line_t8.YData(1);
            
        end
    end
    methods (Access=private,Static)
        function msg1 = convertToDouble(data)
            msg1 = struct();
            msg1.Header = data.Header;
            
            msg1.BRWFDynamixelTemperature = double(data.BRWFDynamixelTemperature)+eps;
            msg1.FRWFDynamixelTemperature = double(data.FRWFDynamixelTemperature)+eps;
            msg1.BLWFDynamixelTemperature = double(data.BLWFDynamixelTemperature)+eps;
            msg1.FLWFDynamixelTemperature = double(data.FLWFDynamixelTemperature)+eps;
            
            msg1.BRWSDynamixelTemperature = double(data.BRWSDynamixelTemperature)+eps;
            msg1.FRWSDynamixelTemperature = double(data.FRWSDynamixelTemperature)+eps;
            msg1.BLWSDynamixelTemperature = double(data.BLWSDynamixelTemperature)+eps;
            msg1.FLWSDynamixelTemperature = double(data.FLWSDynamixelTemperature)+eps;
            
            %assignin('base','data',msg1)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.main_app = calling_app;
            app.UIFigure.Name = 'Motors Temperature Data View';
            app.initializePlot()
            app.initialized = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.main_app.MotorTemperatureButton.Enable = true;
            app.main_app.motor_temp_window = 0;
            
            delete(app)
            
        end

        % Value changed function: ShowGraphsCheckBox
        function ShowGraphsCheckBoxValueChanged(app, event)
            value = app.ShowGraphsCheckBox.Value;
            app.plot_resetted = true;
            
            if value == true
                app.resetPlot()
                app.initial_time_plot = app.last_data.Header.Stamp;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.GaugeIndicatorsPanel = uipanel(app.SummaryTab);
            app.GaugeIndicatorsPanel.Title = 'Gauge Indicators';
            app.BackRightDriveMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.BackRightDriveMotorGaugeLabel.Text = 'Back-Right-Drive-Motor';
            app.BackRightDriveMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.BackRightDriveMotorGauge.Limits = [0 60];
            app.FrontRightDriveMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.FrontRightDriveMotorGaugeLabel.Text = 'Front-Right-Drive-Motor';
            app.FrontRightDriveMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.FrontRightDriveMotorGauge.Limits = [0 60];
            app.BackLeftDriveMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.BackLeftDriveMotorGaugeLabel.Text = 'Back-Left-Drive-Motor';
            app.BackLeftDriveMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.BackLeftDriveMotorGauge.Limits = [0 60];
            app.FrontLeftDriveMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.FrontLeftDriveMotorGaugeLabel.Text = 'Front-Left-Drive-Motor';
            app.FrontLeftDriveMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.FrontLeftDriveMotorGauge.Limits = [0 60];
            app.BackRightSteerMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.BackRightSteerMotorGaugeLabel.Text = 'Back-Right-Steer-Motor';
            app.BackRightSteerMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.BackRightSteerMotorGauge.Limits = [0 60];
            app.FrontRightSteerMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.FrontRightSteerMotorGaugeLabel.Text = 'Front-Right-Steer-Motor';
            app.FrontRightSteerMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.FrontRightSteerMotorGauge.Limits = [0 60];
            app.BackLeftSteerMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.BackLeftSteerMotorGaugeLabel.Text = 'Back-Left-Steer-Motor';
            app.BackLeftSteerMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.BackLeftSteerMotorGauge.Limits = [0 60];
            app.FrontLeftSteerMotorGaugeLabel = uilabel(app.GaugeIndicatorsPanel);
            app.FrontLeftSteerMotorGaugeLabel.Text = 'Front-Left-Steer-Motor';
            app.FrontLeftSteerMotorGauge = uigauge(app.GaugeIndicatorsPanel, 'ninetydegree');
            app.FrontLeftSteerMotorGauge.Limits = [0 60];
            app.OptionsPanel = uipanel(app.SummaryTab);
            app.OptionsPanel.Title = 'Options';
            app.EnableIndicatorsCheckBox = uicheckbox(app.OptionsPanel);
            app.EnableIndicatorsCheckBox.Text = 'Enable Indicators';
            app.ShowGraphsCheckBox = uicheckbox(app.OptionsPanel);
            app.ShowGraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGraphsCheckBoxValueChanged, true);
            app.ShowGraphsCheckBox.Text = 'Show Graphs';
            app.RawDataPanel = uipanel(app.SummaryTab);
            app.RawDataPanel.Title = 'Raw Data';
            app.TemperaturePanel = uipanel(app.RawDataPanel);
            app.TemperaturePanel.Title = 'Temperature';
            app.T1 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T1.ValueDisplayFormat = '%.0f';
            app.T1.Editable = 'off';
            app.T2 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T2.Editable = 'off';
            app.T3 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T3.Editable = 'off';
            app.T4 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T4.Editable = 'off';
            app.T5 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T5.Editable = 'off';
            app.T6 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T6.Editable = 'off';
            app.T7 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T7.Editable = 'off';
            app.T8 = uieditfield(app.TemperaturePanel, 'numeric');
            app.T8.Editable = 'off';
            app.BRDMLabel = uilabel(app.RawDataPanel);
            app.BRDMLabel.Text = 'BRDM';
            app.FRDMLabel = uilabel(app.RawDataPanel);
            app.FRDMLabel.Text = 'FRDM';
            app.BLDMLabel = uilabel(app.RawDataPanel);
            app.BLDMLabel.Text = 'BLDM';
            app.FLDMLabel = uilabel(app.RawDataPanel);
            app.FLDMLabel.Text = 'FLDM';
            app.BRSMLabel = uilabel(app.RawDataPanel);
            app.BRSMLabel.Text = 'BRSM';
            app.FRSMLabel = uilabel(app.RawDataPanel);
            app.FRSMLabel.Text = 'FRSM';
            app.BLSMLabel = uilabel(app.RawDataPanel);
            app.BLSMLabel.Text = 'BLSM';
            app.FLSMLabel = uilabel(app.RawDataPanel);
            app.FLSMLabel.Text = 'FLSM';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, 'Motors temperature')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'Temperature [° C]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temperature_visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end