classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        GeneralTab         matlab.ui.container.Tab
        StartplotCheckBox  matlab.ui.control.CheckBox
        GraphsTab          matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        plot_resetted;
        plot_initialized;
        timer_plot; % Description
        legend_l;
        lines;
        initial_time;
    end
    
    methods (Access = private)
        
        function initializeGraph(app)
            %app.lines = zeros(1,10);
            app.lines = cell(1,10);
            names = {'a','b','c','d','e','f','g','h','i','l'};
            colors = {'-r','-b','-g','-k','-y','-.r','-.b','-.g','-.k','-.y'};
            disp('porcodio')
            hold(app.UIAxes,'on')
            
            for i=1:numel(app.lines)
                app.lines{i} = plot(app.UIAxes,0,0,colors{i});
                disp('ueila')
            end
            app.legend_l = legend(app.UIAxes,names,"AutoUpdate","off"); 
            
            
        end
    end
    methods (Access=private,Static)
        function data = genFakeData()
            data = rand(1,10);
        end
    end
    
    methods (Access=private)
        function initialize(app)
            app.timer_plot = timer();
            app.timer_plot.Period = 0.1;
            app.timer_plot.ExecutionMode = 'fixedRate';
            app.timer_plot.TimerFcn = @(~,~)app.updateGraph;
        end
        function updateGraph(app,fake_in)
            disp('1')
            data = app.genFakeData();
            disp('2')
            
          
            if app.StartplotCheckBox.Value == true
                time = rostime('now') - app.initial_time;
                time1 = time.Sec + time.Nsec/1e9;
                if app.plot_resetted == true
                for i=1:numel(app.lines)
                    app.lines{i}.XData = time1;
                    app.lines{i}.YData = data(i);
                end
                else
                    for i=1:numel(app.lines)
                    app.lines{i}.XData(end+1) = time1;
                    app.lines{i}.YData(end+1) = data(i);
                    end
                end
            end
        end
        function reset(app)
            for i=1:numel(app.lines)
                app.lines{i}.XData = app.lines{i}.XData(1);
                app.lines{i}.YData = app.lines{i}.YData(1);
            end
            
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeGraph()
            app.initialize()
            app.timer_plot.start()
        end

        % Value changed function: StartplotCheckBox
        function StartplotCheckBoxValueChanged(app, event)
            value = app.StartplotCheckBox.Value;
            
            if value == true
                app.reset()
                app.initial_time = rostime("now");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.timer_plot.stop()
            app.timer_plot.delete()
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.StartplotCheckBox = uicheckbox(app.GeneralTab);
            app.StartplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartplotCheckBoxValueChanged, true);
            app.StartplotCheckBox.Text = 'Start plot';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end